classdef ADCmapp < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        ADCmapUIFigure             matlab.ui.Figure
        TabGroup4                  matlab.ui.container.TabGroup
        SequenceparametersTab      matlab.ui.container.Tab
        DicomDirViewField          matlab.ui.control.EditField
        DicomfolderLabel           matlab.ui.control.Label
        AspectRatioViewField       matlab.ui.control.NumericEditField
        AspectratioLabel           matlab.ui.control.Label
        SLTViewField               matlab.ui.control.NumericEditField
        SlicethicknessmmLabel      matlab.ui.control.Label
        FOVViewField               matlab.ui.control.NumericEditField
        FOVmmEditFieldLabel        matlab.ui.control.Label
        NRImagesViewField          matlab.ui.control.NumericEditField
        ImagesLabel                matlab.ui.control.Label
        SlicesViewField            matlab.ui.control.NumericEditField
        SlicesEditFieldLabel       matlab.ui.control.Label
        dimyViewField              matlab.ui.control.NumericEditField
        dimyEditFieldLabel         matlab.ui.control.Label
        dimxViewField              matlab.ui.control.NumericEditField
        dimxEditFieldLabel         matlab.ui.control.Label
        TEViewField                matlab.ui.control.NumericEditField
        TEmsLabel                  matlab.ui.control.Label
        TRViewField                matlab.ui.control.NumericEditField
        TRmsEditFieldLabel         matlab.ui.control.Label
        TabGroup2                  matlab.ui.container.TabGroup
        RawimagesTab               matlab.ui.container.Tab
        GradViewField              matlab.ui.control.EditField
        GradEditFieldLabel         matlab.ui.control.Label
        BvalueViewField            matlab.ui.control.EditField
        BvalueEditFieldLabel       matlab.ui.control.Label
        RawImage                   matlab.ui.control.Image
        ImagewindowlevelPanel      matlab.ui.container.Panel
        LevelEditField             matlab.ui.control.NumericEditField
        LEditFieldLabel            matlab.ui.control.Label
        AutoLWButton               matlab.ui.control.StateButton
        LevelSlider                matlab.ui.control.Slider
        WindowEditField            matlab.ui.control.NumericEditField
        WEditFieldLabel            matlab.ui.control.Label
        WindowSlider               matlab.ui.control.Slider
        MasksettingsPanel          matlab.ui.container.Panel
        ThresholdEditField         matlab.ui.control.NumericEditField
        ThresholdEditFieldLabel    matlab.ui.control.Label
        OpenButton                 matlab.ui.control.Button
        CloseButton                matlab.ui.control.Button
        DilateButton               matlab.ui.control.Button
        ErodeButton                matlab.ui.control.Button
        ThresholdSlider            matlab.ui.control.Slider
        ImageselectionPanel        matlab.ui.container.Panel
        ImageEditField             matlab.ui.control.NumericEditField
        ImageLabel_2               matlab.ui.control.Label
        SlicesEditField            matlab.ui.control.NumericEditField
        SliceEditFieldLabel        matlab.ui.control.Label
        ImageSlider                matlab.ui.control.Slider
        SlicesSlider               matlab.ui.control.Slider
        ImageLabel                 matlab.ui.control.Label
        RawDataFig                 matlab.ui.control.UIAxes
        FitparametersTab           matlab.ui.container.Tab
        UsergradienttablePanel     matlab.ui.container.Panel
        ImportGradTableButton      matlab.ui.control.Button
        BvaluegradientdirectionselectionPanel  matlab.ui.container.Panel
        CheckBox_64                matlab.ui.control.CheckBox
        CheckBox_63                matlab.ui.control.CheckBox
        CheckBox_62                matlab.ui.control.CheckBox
        CheckBox_61                matlab.ui.control.CheckBox
        CheckBox_60                matlab.ui.control.CheckBox
        CheckBox_59                matlab.ui.control.CheckBox
        CheckBox_58                matlab.ui.control.CheckBox
        CheckBox_57                matlab.ui.control.CheckBox
        CheckBox_56                matlab.ui.control.CheckBox
        CheckBox_55                matlab.ui.control.CheckBox
        CheckBox_54                matlab.ui.control.CheckBox
        CheckBox_53                matlab.ui.control.CheckBox
        CheckBox_52                matlab.ui.control.CheckBox
        CheckBox_51                matlab.ui.control.CheckBox
        CheckBox_50                matlab.ui.control.CheckBox
        CheckBox_49                matlab.ui.control.CheckBox
        CheckBox_48                matlab.ui.control.CheckBox
        CheckBox_47                matlab.ui.control.CheckBox
        CheckBox_46                matlab.ui.control.CheckBox
        CheckBox_45                matlab.ui.control.CheckBox
        CheckBox_44                matlab.ui.control.CheckBox
        CheckBox_43                matlab.ui.control.CheckBox
        CheckBox_42                matlab.ui.control.CheckBox
        CheckBox_41                matlab.ui.control.CheckBox
        CheckBox_40                matlab.ui.control.CheckBox
        CheckBox_39                matlab.ui.control.CheckBox
        CheckBox_38                matlab.ui.control.CheckBox
        CheckBox_37                matlab.ui.control.CheckBox
        CheckBox_36                matlab.ui.control.CheckBox
        CheckBox_35                matlab.ui.control.CheckBox
        CheckBox_34                matlab.ui.control.CheckBox
        CheckBox_33                matlab.ui.control.CheckBox
        CheckBox_32                matlab.ui.control.CheckBox
        CheckBox_31                matlab.ui.control.CheckBox
        CheckBox_30                matlab.ui.control.CheckBox
        CheckBox_29                matlab.ui.control.CheckBox
        CheckBox_28                matlab.ui.control.CheckBox
        CheckBox_27                matlab.ui.control.CheckBox
        CheckBox_26                matlab.ui.control.CheckBox
        CheckBox_25                matlab.ui.control.CheckBox
        CheckBox_24                matlab.ui.control.CheckBox
        CheckBox_23                matlab.ui.control.CheckBox
        CheckBox_22                matlab.ui.control.CheckBox
        CheckBox_21                matlab.ui.control.CheckBox
        CheckBox_20                matlab.ui.control.CheckBox
        CheckBox_19                matlab.ui.control.CheckBox
        CheckBox_18                matlab.ui.control.CheckBox
        CheckBox_17                matlab.ui.control.CheckBox
        CheckBox_16                matlab.ui.control.CheckBox
        CheckBox_15                matlab.ui.control.CheckBox
        CheckBox_14                matlab.ui.control.CheckBox
        CheckBox_13                matlab.ui.control.CheckBox
        CheckBox_12                matlab.ui.control.CheckBox
        CheckBox_11                matlab.ui.control.CheckBox
        CheckBox_10                matlab.ui.control.CheckBox
        CheckBox_9                 matlab.ui.control.CheckBox
        CheckBox_8                 matlab.ui.control.CheckBox
        CheckBox_7                 matlab.ui.control.CheckBox
        CheckBox_6                 matlab.ui.control.CheckBox
        CheckBox_5                 matlab.ui.control.CheckBox
        CheckBox_4                 matlab.ui.control.CheckBox
        CheckBox_3                 matlab.ui.control.CheckBox
        CheckBox_2                 matlab.ui.control.CheckBox
        CheckBox_1                 matlab.ui.control.CheckBox
        GoodnessoffitPanel         matlab.ui.container.Panel
        ThresholdLabel             matlab.ui.control.Label
        ThresholdR                 matlab.ui.control.CheckBox
        Rsquare                    matlab.ui.control.NumericEditField
        RsquareLabel               matlab.ui.control.Label
        SliceselectionPanel        matlab.ui.container.Panel
        LastSliceEditField         matlab.ui.control.NumericEditField
        LastsliceLabel             matlab.ui.control.Label
        FirstSliceEditField        matlab.ui.control.NumericEditField
        FirstsliceLabel            matlab.ui.control.Label
        TabGroup3                  matlab.ui.container.TabGroup
        ADCmapTab                  matlab.ui.container.Tab
        BrushEditField             matlab.ui.control.NumericEditField
        brushEditFieldLabel        matlab.ui.control.Label
        EraseButton                matlab.ui.control.Button
        ROIbutton                  matlab.ui.control.Button
        ADCunitLabel3              matlab.ui.control.Label
        ADCunitLabel2              matlab.ui.control.Label
        ADCunitLabel1              matlab.ui.control.Label
        ADCLabel                   matlab.ui.control.Label
        ADCViewField               matlab.ui.control.NumericEditField
        YEditField                 matlab.ui.control.NumericEditField
        YEditFieldLabel            matlab.ui.control.Label
        XEditField                 matlab.ui.control.NumericEditField
        XEditFieldLabel            matlab.ui.control.Label
        ADCImage                   matlab.ui.control.Image
        ColormapButtonGroup        matlab.ui.container.ButtonGroup
        ADCScaleAutoButton         matlab.ui.control.Button
        ADCScaleMaxLabel           matlab.ui.control.Label
        ADCScaleEditField          matlab.ui.control.NumericEditField
        hotButton                  matlab.ui.control.RadioButton
        parulaButton               matlab.ui.control.RadioButton
        winterButton               matlab.ui.control.RadioButton
        autumnButton               matlab.ui.control.RadioButton
        grayButton                 matlab.ui.control.RadioButton
        ImageselectionPanel_2      matlab.ui.container.Panel
        ADCMapSlicesEditField      matlab.ui.control.NumericEditField
        SliceEditField_2Label      matlab.ui.control.Label
        ADCMapSlicesSlider         matlab.ui.control.Slider
        ADCMapLabel                matlab.ui.control.Label
        ADClinearPlot              matlab.ui.control.UIAxes
        ADClogPlot                 matlab.ui.control.UIAxes
        ADCMapFig                  matlab.ui.control.UIAxes
        M0R2Tab                    matlab.ui.container.Tab
        BrushEditField2            matlab.ui.control.NumericEditField
        brushEditField_2Label      matlab.ui.control.Label
        EraseButton2               matlab.ui.control.Button
        ROIbutton2                 matlab.ui.control.Button
        R2Image                    matlab.ui.control.Image
        M0Image                    matlab.ui.control.Image
        ColorMapButtonGroup3       matlab.ui.container.ButtonGroup
        hotButton3                 matlab.ui.control.RadioButton
        parulaButton3              matlab.ui.control.RadioButton
        winterButton3              matlab.ui.control.RadioButton
        autumnButton3              matlab.ui.control.RadioButton
        grayButton3                matlab.ui.control.RadioButton
        ColorMapButtonGroup2       matlab.ui.container.ButtonGroup
        hotButton2                 matlab.ui.control.RadioButton
        parulaButton2              matlab.ui.control.RadioButton
        winterButton2              matlab.ui.control.RadioButton
        autumnButton2              matlab.ui.control.RadioButton
        grayButton2                matlab.ui.control.RadioButton
        ImageSelectorPanel2        matlab.ui.container.Panel
        ADCMapSlicesEditField2     matlab.ui.control.NumericEditField
        SliceEditField_2Label_2    matlab.ui.control.Label
        ADCMapSlicesSlider2        matlab.ui.control.Slider
        R2MapLabel                 matlab.ui.control.Label
        M0MapLabel                 matlab.ui.control.Label
        R2MapFig                   matlab.ui.control.UIAxes
        M0MapFig                   matlab.ui.control.UIAxes
        TabGroup1                  matlab.ui.container.TabGroup
        DashboardTab               matlab.ui.container.Tab
        EstimatedFitTimeViewField  matlab.ui.control.EditField
        EstimatedRegTimeViewField  matlab.ui.control.EditField
        AbortRegButton             matlab.ui.control.Button
        RegistrationDropDown       matlab.ui.control.DropDown
        RegProgressLabel           matlab.ui.control.Label
        RegProgressGauge           matlab.ui.control.LinearGauge
        Lamp2                      matlab.ui.control.Lamp
        RegisterButton             matlab.ui.control.Button
        AbortFitButton             matlab.ui.control.Button
        ExportGIFButton            matlab.ui.control.Button
        Lamp6                      matlab.ui.control.Lamp
        FitProgressLabel           matlab.ui.control.Label
        ExportDCMButton            matlab.ui.control.Button
        ExitButton                 matlab.ui.control.Button
        Lamp5                      matlab.ui.control.Lamp
        FitProgressGauge           matlab.ui.control.LinearGauge
        Lamp4                      matlab.ui.control.Lamp
        FitADCButton               matlab.ui.control.Button
        Lamp3                      matlab.ui.control.Lamp
        MaskButton                 matlab.ui.control.Button
        Lamp1                      matlab.ui.control.Lamp
        LoadMRDfileButton          matlab.ui.control.Button
        MRSImage                   matlab.ui.control.Image
        statusLabel                matlab.ui.control.Label
        StatusImage                matlab.ui.control.Image
        BackgroundBox              matlab.ui.control.TextArea
        MessageBox                 matlab.ui.control.TextArea
        TulpLogo                   matlab.ui.control.Image
        Credits                    matlab.ui.control.Label
        ADCmappingLabel            matlab.ui.control.Label
    end


    %% --------------------------------------------------------------------------------------
    %
    %   ADC mapping tool
    %    
    %
    %   Gustav Strijkers
    %   Amsterdam UMC
    %   Sept 2023
    %
    %
    %   
    %
    %
    %   Version 1.0; nov 2020
    %   - intial version
    %
    %   Version 1.1; April 2021
    %   - bug fix in image aspect ratio
    %
    %   Version 2.0
    %
    %   Version 2.2; Sept 2023
    %   - image registration with Elastix
    %   - many improvements and bug fixes
    %
    %% --------------------------------------------------------------------------------------


    properties (Access = public)

        appVersion = "2.2.2"        % App version
       
        % File variables
        appdir                      % Application directory
        olddir                      % Starting directory
        dicomImportPath             % Dicom import path
        dicomExportPath             % Dicom export path
        gradPath                    % Gradient file import path
        gifExportPath               % gif export path
        dataFile                    % raw data file
        data                        % raw k-space data
        tag                         % file tag for export of dicom files
        dcmInfo                     % Dicom info (tags)
        
        % Flags
        validDataFlag               % valid data file true/false
        validRecoFlag               % valid reco true/false
        validRegFlag                % successful registration true/false
        validFitFlag                % valid fit true/false
        validGradFlag               % valid gradient directions true or false
        abortFitFlag                % abort fit false or true
        abortRegFlag                % abort registration false or true
        roiFlag                     % roi drawing true or false
        
        % Scan properties
        dimensions                  % image dimensions
        parameters                  % image paramters
        binfo                       % info on b-value and diffusion directions
        bValueInfo                  % info on b-value and diffusion directions, cell array
        nr                          % number of images
        dimx                        % nr points readout
        dimy                        % nt points phase encoding
        fov                         % field of view
        aspectRatio                 % field of view ratio
        sl                          % slice thickness
        ns                          % number of slices
        tr                          % repetition time
        te                          % echo time / spacing
        gradTable                   % gradient table
      
        % Images
        images                      % reconstructed raw images (ne, ns, dimx, dimy)
        threshold                   % segmentation threshold (ns,1)
        mask                        % binary image with segmentation (ns, dimx, dimy)
        scalefactor                 % image intensity scale factor
        txt1                        % legend label     
        txt2                        % legend label  
        crossMD                     % cross-hair
        crossM0                     % cross-hair
        roiPoints                   % roi point coordinates
        roiMD                       % roi drawing object in T2 map
        roiM0                       % roi drawing object in M0 image
        structuringElement2D        % 2D structuring element
     
        % Fitting
        adcmap                      % resulting ADCmap (ns, dimx, dimy)
        adccmap                     % ADC color mapping
        m0map                       % resulting m0map (ns, dimx, dimy)
        m0cmap                      % M0 color mapping
        r2map                       % resulting R2 map
        r2cmap                      % R2 color mapping
        nrSelection                 % Image (b-value / gradient dir) selection array
        
        % Colors
        brightred                   % red
        red                         % red
        brightgreen                 % green
        green                       % green
        yellow                      % yellow
        orange                      % orange
        blue                        % blue
        grey                        % grey

        % Sounds
        warningSound                % warning sound
        errorSound                  % error sound
        
    end
    
    
    methods (Access = private)


        %------------------------------------------------
        %
        %  GUI layout
        %
        %------------------------------------------------


        % Traffic lights
        function SetLights(app,code)
            
            if code(1) == 0 app.Lamp1.Color = app.red; end %#ok<*SEPEX>
            if code(2) == 0 app.Lamp2.Color = app.red; end
            if code(3) == 0 app.Lamp3.Color = app.red; end
            if code(4) == 0 app.Lamp4.Color = app.red; end
            if code(5) == 0 app.Lamp5.Color = app.red; end
            if code(6) == 0 app.Lamp6.Color = app.red; end
                
            if code(1) == 1 app.Lamp1.Color = app.green; end
            if code(2) == 1 app.Lamp2.Color = app.green; end
            if code(3) == 1 app.Lamp3.Color = app.green; end
            if code(4) == 1 app.Lamp4.Color = app.green; end
            if code(5) == 1 app.Lamp5.Color = app.green; end
            if code(6) == 1 app.Lamp6.Color = app.green; end
            
            drawnow;
            
        end
        
        
        % Buttons on/off
        function SetButtons(app,code)
        
            if code(1) == 0 app.LoadMRDfileButton.Enable = 'off';   else app.LoadMRDfileButton.Enable = 'on';   end
            if code(2) == 0 
                app.RegisterButton.Enable = 'off';
                app.RegistrationDropDown.Enable = 'off';
            else 
                app.RegisterButton.Enable = 'on';
                app.RegistrationDropDown.Enable = 'on';
            end
            if code(3) == 0 app.MaskButton.Enable = 'off';     else app.MaskButton.Enable = 'on';     end
            if code(4) == 0 app.FitADCButton.Enable = 'off';         else app.FitADCButton.Enable = 'on';         end
            if code(5) == 0 app.ExportDCMButton.Enable = 'off';   else app.ExportDCMButton.Enable = 'on';   end
            if code(6) == 0 app.ExportGIFButton.Enable = 'off';     else app.ExportGIFButton.Enable = 'on';     end
            if code(7) == 0 app.ExitButton.Enable = 'off';          else app.ExitButton.Enable = 'on';          end
        
            drawnow;
            
        end
        
        
        % Switch fit parameters buttons on/off
        function SetFitButtons(app,code)
            
            if code == 0
                
                app.FirstSliceEditField.Enable = 'off';
                app.LastSliceEditField.Enable = 'off';
                app.Rsquare.Enable = 'off';
                app.AbortFitButton.Enable = 'off';
                app.ThresholdR.Enable = 'off';
                app.ImportGradTableButton.Enable = 'off';
                
                app.CheckBox_1.Enable = 'off';  app.CheckBox_2.Enable = 'off';  app.CheckBox_3.Enable = 'off';  app.CheckBox_4.Enable = 'off';
                app.CheckBox_5.Enable = 'off';  app.CheckBox_6.Enable = 'off';  app.CheckBox_7.Enable = 'off';  app.CheckBox_8.Enable = 'off';
                app.CheckBox_9.Enable = 'off';  app.CheckBox_10.Enable = 'off'; app.CheckBox_11.Enable = 'off'; app.CheckBox_12.Enable = 'off';
                app.CheckBox_13.Enable = 'off'; app.CheckBox_14.Enable = 'off'; app.CheckBox_15.Enable = 'off'; app.CheckBox_16.Enable = 'off';
                app.CheckBox_17.Enable = 'off'; app.CheckBox_18.Enable = 'off'; app.CheckBox_19.Enable = 'off'; app.CheckBox_20.Enable = 'off';
                app.CheckBox_21.Enable = 'off'; app.CheckBox_22.Enable = 'off'; app.CheckBox_23.Enable = 'off'; app.CheckBox_24.Enable = 'off';
                app.CheckBox_25.Enable = 'off'; app.CheckBox_26.Enable = 'off'; app.CheckBox_27.Enable = 'off'; app.CheckBox_28.Enable = 'off';
                app.CheckBox_29.Enable = 'off'; app.CheckBox_30.Enable = 'off'; app.CheckBox_31.Enable = 'off'; app.CheckBox_32.Enable = 'off';
                app.CheckBox_33.Enable = 'off'; app.CheckBox_34.Enable = 'off'; app.CheckBox_35.Enable = 'off'; app.CheckBox_36.Enable = 'off';
                app.CheckBox_37.Enable = 'off'; app.CheckBox_38.Enable = 'off'; app.CheckBox_39.Enable = 'off'; app.CheckBox_40.Enable = 'off';
                app.CheckBox_41.Enable = 'off'; app.CheckBox_42.Enable = 'off'; app.CheckBox_43.Enable = 'off'; app.CheckBox_44.Enable = 'off';
                app.CheckBox_45.Enable = 'off'; app.CheckBox_46.Enable = 'off'; app.CheckBox_47.Enable = 'off'; app.CheckBox_48.Enable = 'off';
                app.CheckBox_49.Enable = 'off'; app.CheckBox_50.Enable = 'off'; app.CheckBox_51.Enable = 'off'; app.CheckBox_52.Enable = 'off';
                app.CheckBox_53.Enable = 'off'; app.CheckBox_54.Enable = 'off'; app.CheckBox_55.Enable = 'off'; app.CheckBox_56.Enable = 'off';
                app.CheckBox_57.Enable = 'off'; app.CheckBox_58.Enable = 'off'; app.CheckBox_59.Enable = 'off'; app.CheckBox_60.Enable = 'off';
                app.CheckBox_61.Enable = 'off'; app.CheckBox_62.Enable = 'off'; app.CheckBox_63.Enable = 'off'; app.CheckBox_64.Enable = 'off';
             
            else
            
                app.FirstSliceEditField.Enable = 'on';
                app.LastSliceEditField.Enable = 'on';
                app.Rsquare.Enable = 'on';
                app.AbortFitButton.Enable = 'on';
                app.ThresholdR.Enable = 'on';
                app.ImportGradTableButton.Enable = 'on';
                
                app.CheckBox_1.Enable = 'on';
                if app.nr > 1 app.CheckBox_2.Enable = 'on'; end;        if app.nr > 32 app.CheckBox_33.Enable = 'on'; end
                if app.nr > 2 app.CheckBox_3.Enable = 'on'; end;        if app.nr > 33 app.CheckBox_34.Enable = 'on'; end
                if app.nr > 3 app.CheckBox_4.Enable = 'on'; end;        if app.nr > 34 app.CheckBox_35.Enable = 'on'; end
                if app.nr > 4 app.CheckBox_5.Enable = 'on'; end;        if app.nr > 35 app.CheckBox_36.Enable = 'on'; end
                if app.nr > 5 app.CheckBox_6.Enable = 'on'; end;        if app.nr > 36 app.CheckBox_37.Enable = 'on'; end
                if app.nr > 6 app.CheckBox_7.Enable = 'on'; end;        if app.nr > 37 app.CheckBox_38.Enable = 'on'; end
                if app.nr > 7 app.CheckBox_8.Enable = 'on'; end;        if app.nr > 38 app.CheckBox_39.Enable = 'on'; end
                if app.nr > 8 app.CheckBox_9.Enable = 'on'; end;        if app.nr > 39 app.CheckBox_40.Enable = 'on'; end
                if app.nr > 9 app.CheckBox_10.Enable = 'on'; end;       if app.nr > 40 app.CheckBox_41.Enable = 'on'; end
                if app.nr > 10 app.CheckBox_11.Enable = 'on'; end;      if app.nr > 41 app.CheckBox_42.Enable = 'on'; end
                if app.nr > 11 app.CheckBox_12.Enable = 'on'; end;      if app.nr > 42 app.CheckBox_43.Enable = 'on'; end
                if app.nr > 12 app.CheckBox_13.Enable = 'on'; end;      if app.nr > 43 app.CheckBox_44.Enable = 'on'; end
                if app.nr > 13 app.CheckBox_14.Enable = 'on'; end;      if app.nr > 44 app.CheckBox_45.Enable = 'on'; end
                if app.nr > 14 app.CheckBox_15.Enable = 'on'; end;      if app.nr > 45 app.CheckBox_46.Enable = 'on'; end
                if app.nr > 15 app.CheckBox_16.Enable = 'on'; end;      if app.nr > 46 app.CheckBox_47.Enable = 'on'; end
                if app.nr > 16 app.CheckBox_17.Enable = 'on'; end;      if app.nr > 47 app.CheckBox_48.Enable = 'on'; end
                if app.nr > 17 app.CheckBox_18.Enable = 'on'; end;      if app.nr > 48 app.CheckBox_49.Enable = 'on'; end
                if app.nr > 18 app.CheckBox_19.Enable = 'on'; end;      if app.nr > 49 app.CheckBox_50.Enable = 'on'; end
                if app.nr > 19 app.CheckBox_20.Enable = 'on'; end;      if app.nr > 50 app.CheckBox_51.Enable = 'on'; end
                if app.nr > 20 app.CheckBox_21.Enable = 'on'; end;      if app.nr > 51 app.CheckBox_52.Enable = 'on'; end
                if app.nr > 21 app.CheckBox_22.Enable = 'on'; end;      if app.nr > 52 app.CheckBox_53.Enable = 'on'; end
                if app.nr > 22 app.CheckBox_23.Enable = 'on'; end;      if app.nr > 53 app.CheckBox_54.Enable = 'on'; end
                if app.nr > 23 app.CheckBox_24.Enable = 'on'; end;      if app.nr > 54 app.CheckBox_55.Enable = 'on'; end
                if app.nr > 24 app.CheckBox_25.Enable = 'on'; end;      if app.nr > 55 app.CheckBox_56.Enable = 'on'; end
                if app.nr > 25 app.CheckBox_26.Enable = 'on'; end;      if app.nr > 56 app.CheckBox_57.Enable = 'on'; end
                if app.nr > 26 app.CheckBox_27.Enable = 'on'; end;      if app.nr > 57 app.CheckBox_58.Enable = 'on'; end
                if app.nr > 27 app.CheckBox_28.Enable = 'on'; end;      if app.nr > 58 app.CheckBox_59.Enable = 'on'; end
                if app.nr > 28 app.CheckBox_29.Enable = 'on'; end;      if app.nr > 59 app.CheckBox_60.Enable = 'on'; end
                if app.nr > 29 app.CheckBox_30.Enable = 'on'; end;      if app.nr > 60 app.CheckBox_61.Enable = 'on'; end
                if app.nr > 30 app.CheckBox_31.Enable = 'on'; end;      if app.nr > 61 app.CheckBox_62.Enable = 'on'; end
                if app.nr > 31 app.CheckBox_32.Enable = 'on'; end;      if app.nr > 62 app.CheckBox_63.Enable = 'on'; end
                if app.nr > 63 app.CheckBox_64.Enable = 'on'; end
                
            end
                                   
            drawnow;
            
        end
        
        
        % Switch image selection buttons on/off
        function SetImageSelectionButtons(app,code)
            
            if code == 0
                app.SlicesEditField.Enable = 'off';
                app.SlicesSlider.Enable = 'off';
                app.ImageEditField.Enable = 'off';
                app.ImageSlider.Enable = 'off';
            else
                app.SlicesEditField.Enable = 'on';
                app.SlicesSlider.Enable = 'on';
                app.ImageEditField.Enable = 'on';
                app.ImageSlider.Enable = 'on';
            end
            
            drawnow;
            
        end
        
        
        % Switch image segmentation buttons on/off
        function SetSegmentationButtons(app,code)
            
            if code == 0
                app.ThresholdEditField.Enable = 'off';
                app.ThresholdSlider.Enable = 'off';
                app.ErodeButton.Enable = 'off';
                app.DilateButton.Enable = 'off';
                app.CloseButton.Enable = 'off';
                app.OpenButton.Enable = 'off';
            else
                app.ThresholdEditField.Enable = 'on';
                app.ThresholdSlider.Enable = 'on';
                app.ErodeButton.Enable = 'on';
                app.DilateButton.Enable = 'on';
                app.CloseButton.Enable = 'on';
                app.OpenButton.Enable = 'on';
            end
            
            drawnow;
            
        end
      
        
        % Initialize the GUI
        function InitializeUI(app)
            
            % Message
            app.TextMessage('Initializing UI ...');

            % Credits
            app.Credits.Text = strcat("Gustav Strijkers, Amsterdam UMC, Version ",app.appVersion);

            % Load sounds
            [app.warningSound,~] = audioread('Glass.aiff');
            [app.errorSound,~] = audioread('Ping.aiff');
            
            % Define app colors
            app.brightred = 'Red';
            app.red = '#D95319';
            app.brightgreen = 'Green'; 
            app.green = '#6CB557';
            app.yellow = 'Yellow';
            app.orange = '#FDA400';
            app.blue = '#0073BE';
            app.grey = '#787878';
            
            % Logo's
            app.TulpLogo.ImageSource = 'AmsterdamUMC.gif';
            app.MRSImage.ImageSource = 'MRS.gif';
            
            % Icons
            app.LoadMRDfileButton.Icon = 'disk.png';
            app.RegisterButton.Icon = 'sort.gif';
            app.MaskButton.Icon = 'threshold.gif';
            app.FitADCButton.Icon = 'exponential.gif';
            app.ExportDCMButton.Icon = 'disk.png';
            app.ExportGIFButton.Icon = 'disk.png';
            app.ExitButton.Icon = 'exit.png';
            app.StatusImage.ImageSource = 'statusokay.gif';
            app.ADCImage.ImageSource = 'square.gif';
            app.RawImage.ImageSource = 'square.gif';
            app.M0Image.ImageSource = 'square.gif';
            app.R2Image.ImageSource = 'square.gif';
            
            % set lights and buttons
            app.SetLights([0,0,0,0,0,0]);
            app.SetButtons([1,0,0,0,0,0,1]);
            app.SetFitButtons(0);
            app.SetImageSelectionButtons(0);
            app.SetSegmentationButtons(0);
            
            % Set some plot properties
            app.RawDataFig.Toolbar.Visible = 'off';
            app.ADCMapFig.Toolbar.Visible = 'off';
            app.M0MapFig.Toolbar.Visible = 'off';
            app.ADClogPlot.Toolbar.Visible = 'off';
            app.ADClinearPlot.Toolbar.Visible = 'off';
            
            % Switch off plot interactivity
            app.RawDataFig.Interactions = [];
            app.ADCMapFig.Interactions = [];
            app.M0MapFig.Interactions = [];
            app.ADClogPlot.Interactions = [];
            app.ADClinearPlot.Interactions = [];
            
            drawnow;
            
        end


        %------------------------------------------------
        %
        %  Figures and Plots
        %
        %------------------------------------------------


        % Displays the mask in the raw image data
        function PlotImageFig(app)
          
            if app.validRecoFlag

                cla(app.RawDataFig);
                hold(app.RawDataFig,'on');

                % Define the colormap
                % Since it is not possible to have 2 colormaps in 1 figure, the
                % colormap is split in a grayscale part [0 1] and a red-color overlay part [1 2]
                cmap1 = [linspace(0,1,128)',linspace(0,1,128)',linspace(0,1,128)'];
                cmap2 = [linspace(0,1,128)', zeros(128,2)];
                cmap = [cmap1;cmap2];
                cmap(cmap > 1) = 1;

                % Image to show
                im = squeeze(app.images(app.ImageEditField.Value,app.SlicesEditField.Value,:,:));

                % Scale
                if app.AutoLWButton.Value == 1
                    maxIm = max(im(:));
                    app.WindowEditField.Value = maxIm;
                    app.WindowSlider.Value = maxIm;
                    app.LevelEditField.Value = maxIm/2;
                    app.LevelSlider.Value = maxIm/2;
                end

                % Window and Level
                im = (1/app.WindowEditField.Value)*(im - app.LevelEditField.Value + app.WindowEditField.Value/2);
                im(im<0) = 0;
                im(im>0.9999) = 0.9999;

                [dx,dy] = size(squeeze(app.images(app.ImageEditField.Value,app.SlicesEditField.Value,:,:)));
                xlim(app.RawDataFig,[1,dx]);
                ylim(app.RawDataFig,[1,dy]);

                background = imshow(rot90(im),[0,2],'Colormap',cmap,'InitialMagnification','fit','Parent',app.RawDataFig); %#ok<NASGU>

                % Overlay image with the mask
                imo = squeeze(2-app.mask(app.SlicesEditField.Value,:,:));
                alpha = rot90(squeeze(0.7-0.7*app.mask(app.SlicesEditField.Value,:,:)));
                overlay = imshow(rot90(imo),[0 2],'Colormap',cmap,'InitialMagnification','fit','Parent',app.RawDataFig);
                set(overlay,'AlphaData',alpha);

                % Image aspect ratio
                daspect(app.RawDataFig,[app.aspectRatio 1 1]);

                hold(app.RawDataFig,'off');
                drawnow;

                % Set some limits now
                app.ThresholdEditField.Limits = [0 max(app.images(1,app.SlicesEditField.Value,:))+1];
                app.ThresholdEditField.Value = app.threshold(app.SlicesEditField.Value);
                app.ThresholdSlider.Limits = [0 max(app.images(1,app.SlicesEditField.Value,:))+1];
                app.ThresholdSlider.Value = app.threshold(app.SlicesEditField.Value);

            end
                        
        end % PlotRawImages
        
        
        % Plots the maps for the current slice
        function PlotMaps(app)

            if app.validFitFlag

                hold(app.ADCMapFig,'on');
                hold(app.M0MapFig,'on');
                hold(app.R2MapFig,'on');

                % Plot the ADC map
                [dx,dy] = size(squeeze(app.adcmap(app.ADCMapSlicesEditField.Value,:,:)));
                xlim(app.ADCMapFig,[1,dx]);
                ylim(app.ADCMapFig,[1,dy]);
                im = squeeze(app.adcmap(app.ADCMapSlicesEditField.Value,:,:));

                imshow(rot90(im),[0 app.ADCScaleEditField.Value],'Colormap',app.adccmap,'InitialMagnification','fit','Parent',app.ADCMapFig);
                daspect(app.ADCMapFig,[app.aspectRatio 1 1]);
                colorbar(app.ADCMapFig,'east','Ticks',[]);

                % ROI display if present
                if app.roiFlag && ~isempty(app.roiPoints)
                    zColumn = app.roiPoints(:,3);
                    plotPoints = app.roiPoints(zColumn==app.ADCMapSlicesEditField.Value, :);
                    app.PlotPoint(plotPoints(:,1),plotPoints(:,2),app.dimy);
                end

                % Estimate a suitable scale for the M0 map
                m0scale = round(2*mean(nonzeros(squeeze(app.m0map(app.ADCMapSlicesEditField.Value,:)))));
                m0scale(isnan(m0scale)) = 100;
                m0scale(m0scale<1) = 1;

                % Plot the M0 map
                [dx,dy] = size(squeeze(app.m0map(app.ADCMapSlicesEditField.Value,:,:)));
                xlim(app.M0MapFig,[1,dx]);
                ylim(app.M0MapFig,[1,dy]);
                im = squeeze(app.m0map(app.ADCMapSlicesEditField.Value,:,:));
                imshow(rot90(im),[0 m0scale],'Colormap',app.m0cmap,'InitialMagnification','fit','Parent',app.M0MapFig);
                daspect(app.M0MapFig,[app.aspectRatio 1 1]);

                % Plot the R2 map
                [dx,dy] = size(squeeze(app.r2map(app.ADCMapSlicesEditField.Value,:,:)));
                xlim(app.R2MapFig,[1,dx]);
                ylim(app.R2MapFig,[1,dy]);
                im = squeeze(app.r2map(app.ADCMapSlicesEditField.Value,:,:));
                imshow(rot90(im),[app.Rsquare.Value 1],'Colormap',app.r2cmap,'InitialMagnification','fit','Parent',app.R2MapFig);
                daspect(app.R2MapFig,[app.aspectRatio 1 1]);
                colorbar(app.R2MapFig,'east','Ticks',[app.Rsquare.Value, 1]);
                delete(app.txt1);
                delete(app.txt2);
                app.txt1 = text(app.R2MapFig,0.85,0.95,'1.0','Units','normalized','Color',app.grey,'FontSize',14);
                app.txt2 = text(app.R2MapFig,0.85,0.05,num2str(app.Rsquare.Value),'Units','normalized','Color',app.grey,'FontSize',14);

                hold(app.ADCMapFig,'off');
                hold(app.M0MapFig,'off');
                hold(app.R2MapFig,'off');
                drawnow;

            end
            
        end % PlotMaps


        % Plots the fitresults in the 2 fit-windows
        function PlotFits(app)

            if app.validFitFlag

                cla(app.ADClinearPlot);
                cla(app.ADClogPlot);
                hold(app.ADClinearPlot,'on');
                hold(app.ADClogPlot,'on');

                % Colormap for datapoints
                cmap = [hex2rgb(app.red) ; hex2rgb(app.blue)];
                tecolorflag = cmap(app.nrSelection + 1,:);
                tecolorflag = tecolorflag(1:app.nr,:);

                if app.roiFlag
                    % ROI average
                    xcor = app.roiPoints(:,1);
                    ycor = app.roiPoints(:,2);
                    zcor = app.roiPoints(:,3);
                    adc = zeros(length(xcor),1);
                    m0 = zeros(length(xcor),1);
                    datapoints = zeros(size(app.images,1),length(xcor));
                    for i = 1:length(xcor)
                        adc(i) = app.adcmap(zcor(i),xcor(i),ycor(i));
                        m0(i) = app.m0map(zcor(i),xcor(i),ycor(i));
                        datapoints(:,i) = app.images(:,zcor(i),xcor(i),ycor(i));
                    end
                    datapoints(:,adc==0) = NaN;
                    m0(adc==0) = NaN;
                    adc(adc==0) = NaN;
                    datapoints = mean(datapoints,2,'omitnan');
                    m0 = mean(m0,'omitnan');
                    adc = mean(adc,'omitnan');
                else
                    % Cross data
                    xcor = app.XEditField.Value;
                    ycor = app.YEditField.Value;
                    zcor = app.ADCMapSlicesEditField.Value;
                    adc = app.adcmap(zcor,xcor,ycor);
                    m0 = app.m0map(zcor,xcor,ycor);
                    datapoints = app.images(:,zcor,xcor,ycor);
                end

                b = zeros(app.nr,1);
                for i = 1:app.nr
                    b(i) = app.binfo(1+(i-1)*app.ns).bvalue;
                end

                app.ADCViewField.Value = 0;
                if (adc>0) && (m0>0)

                    % fit
                    a = 0 : 1.1*max(b(:));
                    s = m0*exp(-a.*adc/1000);

                    % plot linear
                    scatter(app.ADClinearPlot,b,datapoints,[],tecolorflag,'filled');
                    plot(app.ADClinearPlot,a,s);
                    xlim(app.ADClinearPlot,[-0.5, 1.1*max(b)]);
                    ylim(app.ADClinearPlot,[-0.5, 1.1*max(s)]);

                    % plot log
                    scatter(app.ADClogPlot,b,log(datapoints),[],tecolorflag,'filled');
                    plot(app.ADClogPlot,a,log(s));
                    xlim(app.ADClogPlot,[-0.5, 1.1*max(b)]);
                    ylim(app.ADClogPlot,[0.9*min(log(s)), 1.01*max(log(s))]);

                    % show ADC value in app
                    app.ADCViewField.Value = adc;

                end

                hold(app.ADClinearPlot,'off');
                hold(app.ADClogPlot,'off');

            end
           
        end % PlotFits


        % Draw a cross-hair on the ADC and M0 maps
        function PlotCross(app,xcor,ycor,ysize)

            if app.validFitFlag

                delete(app.crossMD);
                delete(app.crossM0);

                if app.autumnButton.Value == 1 || app.hotButton.Value == 1
                    clr = 'g';
                else
                    clr = 'r';
                end

                app.crossMD = drawcrosshair(app.ADCMapFig, 'Position', [xcor ysize-ycor+1], 'Color', clr, 'LineWidth', 1);
                app.crossM0 = drawcrosshair(app.M0MapFig, 'Position', [xcor ysize-ycor+1], 'Color', 'r', 'LineWidth', 1);

            end
        
        end % PlotCross


        % Draw points on the ADC and M0 maps
        function PlotPoint(app,xcor,ycor,ysize)

            if app.validFitFlag

                if app.autumnButton.Value == 1 || app.hotButton.Value == 1
                    clr = 'g';
                else
                    clr = 'r';
                end

                hold(app.ADCMapFig,'on');
                delete(app.roiMD);
                app.roiMD = plot(app.ADCMapFig, xcor, ysize-ycor+1, 's', 'MarkerSize', 4, 'Color', clr, 'MarkerFaceColor', clr);
                hold(app.ADCMapFig,'off');

                hold(app.M0MapFig,'on');
                delete(app.roiM0);
                app.roiM0 = plot(app.M0MapFig, xcor, ysize-ycor+1, 's', 'MarkerSize', 4, 'Color', 'r', 'MarkerFaceColor', clr);
                hold(app.M0MapFig,'off');

            end

        end % PlotPoint


        %------------------------------------------------
        %
        %  Parameters and settings
        %
        %------------------------------------------------


        % Sets global parameters
        function SetParameters(app)
           
            % Message
            app.TextMessage('Setting scan parameters ...');
            
            % Sequence parameters
            app.dimx = size(app.data,2);
            app.dimy = size(app.data,3);
            app.fov = app.dcmInfo{1}.FieldOfViewDimensions(1);
           
            % Image aspect ratio
            app.AspectRatioViewField.Value = (app.dcmInfo{1}.FieldOfViewDimensions(2)/app.dcmInfo{1}.FieldOfViewDimensions(1));
            app.aspectRatio = (app.dcmInfo{1}.FieldOfViewDimensions(2)/app.dcmInfo{1}.FieldOfViewDimensions(1))*(app.dimx/app.dimy);
            
            app.sl = app.dcmInfo{1}.FieldOfViewDimensions(3);
            app.tr = app.dcmInfo{1}.RepetitionTime;
            app.te = app.dcmInfo{1}.EchoTime;
            app.ns = length(unique([app.binfo.positions]','rows'));
            app.nr = round(size(app.data,1)/app.ns);
            
            % Show parameters & set limits
            app.TRViewField.Value = app.tr;
            app.TEViewField.Value = app.te;
            app.NRImagesViewField.Value = app.nr;
            app.dimxViewField.Value = app.dimx;
            app.dimyViewField.Value = app.dimy;
            app.SlicesViewField.Value = app.ns;
            app.SlicesEditField.Value = 1;
            app.SlicesEditField.Limits = [1, app.ns];
            app.SlicesSlider.Value = 1;
            app.SlicesSlider.Limits = [1, app.ns];
            app.ImageEditField.Value = 1;
            app.ImageEditField.Limits = [1, app.nr];
            app.ImageSlider.Value = 1;
            app.ImageSlider.Limits = [1, app.nr];
            app.ADCMapSlicesEditField.Limits = [1, app.ns];
            app.ADCMapSlicesSlider.Limits = [1, app.ns];
            app.ADCMapSlicesEditField2.Limits = [1, app.ns];
            app.ADCMapSlicesSlider2.Limits = [1, app.ns];
            app.SLTViewField.Value = app.sl;
            app.FOVViewField.Value = app.fov;
            
            app.adccmap = parula; 
            app.m0cmap = gray; 
            app.r2cmap = autumn; 
            app.adccmap(1,:) = [0 0 0];
            app.m0cmap(1,:) = [0 0 0];
            app.r2cmap(1,:) = [0 0 0];
            app.FirstSliceEditField.Value = 1;
            app.FirstSliceEditField.Limits = [1 app.ns];
            app.LastSliceEditField.Limits = [1 app.ns];
            app.LastSliceEditField.Value = app.ns;
            app.abortFitFlag = false;
            
            % Define empty m0 and ADCmap
            app.m0map = zeros(app.ns,app.dimx,app.dimy);
            app.adcmap = zeros(app.ns,app.dimx,app.dimy);
            app.r2map = zeros(app.ns,app.dimx,app.dimy);
            
            % Images
            app.images = [];
            app.images = reshape(app.data,app.ns,app.nr,app.dimx,app.dimy);
            app.images = permute(app.images,[2 1 3 4]);
            
            % Normalize to convenient range
            app.images = round(32767*app.images/max(app.images(:)));
            app.validRecoFlag = true;

            % Window and level
            app.WindowEditField.Value = 32767;
            app.LevelEditField.Value = 16384;
            app.WindowSlider.Value = 32767;
            app.LevelSlider.Value = 16384;
            app.AutoLWButton.Value = 1;
            
            % Define mask initially with ones
            app.mask = ones(app.ns,app.dimx,app.dimy);

            % Roi stuff
            app.structuringElement2D = strel("disk",1);
            app.roiPoints = [];
            
            % Reset treshold values
            app.threshold = zeros(1,app.ns);
            
            % Initial b-value and grad direction
            indx = app.SlicesEditField.Value + app.ns*(app.ImageEditField.Value - 1);
            app.BvalueViewField.Value = num2str(app.binfo(indx).bvalue);
            app.GradViewField.Value = num2str(app.binfo(indx).grads');
            app.SwitchOnAll;
            
        end % SetParameters
    
        
        % Make b-value selection array
        function SelectData(app)
            
            app.nrSelection = [...
                app.CheckBox_1.Value  app.CheckBox_2.Value  app.CheckBox_3.Value  app.CheckBox_4.Value ...
                app.CheckBox_5.Value  app.CheckBox_6.Value  app.CheckBox_7.Value  app.CheckBox_8.Value ...
                app.CheckBox_9.Value  app.CheckBox_10.Value app.CheckBox_11.Value app.CheckBox_12.Value ...
                app.CheckBox_13.Value app.CheckBox_14.Value app.CheckBox_15.Value app.CheckBox_16.Value ...
                app.CheckBox_17.Value app.CheckBox_18.Value app.CheckBox_19.Value app.CheckBox_20.Value ...
                app.CheckBox_21.Value app.CheckBox_22.Value app.CheckBox_23.Value app.CheckBox_24.Value ...
                app.CheckBox_25.Value app.CheckBox_26.Value app.CheckBox_27.Value app.CheckBox_28.Value ...
                app.CheckBox_29.Value app.CheckBox_30.Value app.CheckBox_31.Value app.CheckBox_32.Value ...
                app.CheckBox_33.Value app.CheckBox_34.Value app.CheckBox_35.Value app.CheckBox_36.Value ...
                app.CheckBox_37.Value app.CheckBox_38.Value app.CheckBox_39.Value app.CheckBox_40.Value ...
                app.CheckBox_41.Value app.CheckBox_42.Value app.CheckBox_43.Value app.CheckBox_44.Value ...
                app.CheckBox_45.Value app.CheckBox_46.Value app.CheckBox_47.Value app.CheckBox_48.Value ...
                app.CheckBox_49.Value app.CheckBox_50.Value app.CheckBox_51.Value app.CheckBox_52.Value ...
                app.CheckBox_53.Value app.CheckBox_54.Value app.CheckBox_55.Value app.CheckBox_56.Value ...
                app.CheckBox_57.Value app.CheckBox_58.Value app.CheckBox_59.Value app.CheckBox_60.Value ...
                app.CheckBox_61.Value app.CheckBox_62.Value app.CheckBox_63.Value app.CheckBox_64.Value ...
                ];
            app.nrSelection = [app.nrSelection ones(1,1024)];
            app.nrSelection = app.nrSelection(1:app.nr);
            
        end
        
        % Set default values for which b-values to include with fitting
        function SwitchOnAll(app)
            
                app.CheckBox_1.Value = 1; app.CheckBox_2.Value = 1; app.CheckBox_3.Value = 1; app.CheckBox_4.Value = 1; ...
                app.CheckBox_5.Value = 1; app.CheckBox_6.Value = 1; app.CheckBox_7.Value = 1; app.CheckBox_8.Value = 1; ...
                app.CheckBox_9.Value = 1; app.CheckBox_10.Value = 1; app.CheckBox_11.Value = 1; app.CheckBox_12.Value = 1; ...
                app.CheckBox_13.Value = 1; app.CheckBox_14.Value = 1; app.CheckBox_15.Value = 1; app.CheckBox_16.Value = 1; ...
                app.CheckBox_17.Value = 1; app.CheckBox_18.Value = 1; app.CheckBox_19.Value = 1; app.CheckBox_20.Value = 1; ...
                app.CheckBox_21.Value = 1; app.CheckBox_22.Value = 1; app.CheckBox_23.Value = 1; app.CheckBox_24.Value = 1; ...
                app.CheckBox_25.Value = 1; app.CheckBox_26.Value = 1; app.CheckBox_27.Value = 1; app.CheckBox_28.Value = 1; ...
                app.CheckBox_29.Value = 1; app.CheckBox_30.Value = 1; app.CheckBox_31.Value = 1; app.CheckBox_32.Value = 1; ...
                app.CheckBox_33.Value = 1; app.CheckBox_34.Value = 1; app.CheckBox_35.Value = 1; app.CheckBox_36.Value = 1; ...
                app.CheckBox_37.Value = 1; app.CheckBox_38.Value = 1; app.CheckBox_39.Value = 1; app.CheckBox_40.Value = 1; ...
                app.CheckBox_41.Value = 1; app.CheckBox_42.Value = 1; app.CheckBox_43.Value = 1; app.CheckBox_44.Value = 1; ...
                app.CheckBox_45.Value = 1; app.CheckBox_46.Value = 1; app.CheckBox_47.Value = 1; app.CheckBox_48.Value = 1; ...
                app.CheckBox_49.Value = 1; app.CheckBox_50.Value = 1; app.CheckBox_51.Value = 1; app.CheckBox_52.Value = 1; ...
                app.CheckBox_53.Value = 1; app.CheckBox_54.Value = 1; app.CheckBox_55.Value = 1; app.CheckBox_56.Value = 1; ...
                app.CheckBox_57.Value = 1; app.CheckBox_58.Value = 1; app.CheckBox_59.Value = 1; app.CheckBox_60.Value = 1; ...
                app.CheckBox_61.Value = 1; app.CheckBox_62.Value = 1; app.CheckBox_63.Value = 1; app.CheckBox_64.Value = 1;
            
        end


    end % methods


    % ----------------------------------------------
    %
    % Public functions, available outside the app
    %
    % ----------------------------------------------
    
    methods (Access = public)
        

        % Text messages in the app
        function TextMessage(app, message)

            try
                app.MessageBox.Value = [message ; app.MessageBox.Value];
                app.MessageBox.Value = app.MessageBox.Value(1:50,1);
                drawnow;
            catch
            end

        end % TextMessage


        % Status icon
        function SetStatus(app, status)
            
            switch status
                
                case 0
                    app.StatusImage.ImageSource = 'statusokay.gif';
                    app.statusLabel.Text = 'OK';
                    app.statusLabel.FontColor = app.green;
                    
                case 1
                    app.StatusImage.ImageSource = 'statuswarning.gif';
                    app.statusLabel.Text = 'warning';
                    app.statusLabel.FontColor = app.orange;
                    sound(app.warningSound,48000);
                    
                case 2
                    app.StatusImage.ImageSource = 'statuserror.gif';
                    app.statusLabel.Text = 'error';
                    app.statusLabel.FontColor = app.brightred;
                    sound(app.errorSound,48000);
                case 3
                    app.StatusImage.ImageSource = 'statuswait.gif';
                    app.statusLabel.Text = 'busy';
                    app.statusLabel.FontColor = 'black';
                    
            end
            
            drawnow;
            
        end
               
        
    end % methods (Access = public)


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function StartupFcn(app)
            
            % main window noname
            app.ADCmapUIFigure.Name = '';
            
            % start the parallel pool if it is not running already
            p = gcp('nocreate');
            if isempty(p)
                try
                    parpool(8);
                catch
                    parpool;
                end
            end
            
            % empty file import and export paths
            app.dicomImportPath = '';
            app.dicomExportPath = '';
            app.gifExportPath = '';
            app.gradPath = '';
                       
            % add subfolders to search path
            if (~isdeployed)
                app.olddir = pwd;
                info = dir(which('ADCmapp.mlapp'));
                app.appdir = info.folder;
                cd(app.appdir);
                addpath(genpath(pwd));
            end
            
            % disable unnecessary warnings
            clc;
            warning off;
            
            % Initialize user interface
            app.InitializeUI;

            % PC or MAC
            try
                if ismac()
                    [~,result] = system('uname -v');
                    is_m1_mac = any(strfind(result,'ARM64'));
                    if is_m1_mac
                        app.TextMessage('Apple arm64 version');
                    else
                        app.TextMessage('Apple Intel version');
                    end
                else
                    app.TextMessage('PC Intel version');
                end
            catch ME
                app.TextMessage(ME.message);
            end

            % Elastix
            try
                if ismac || isunix
                    [~, result] = system('echo -n $PATH');
                    if ~contains(result,':/usr/local/bin:/usr/local/lib')
                        result = [result ':/usr/local/bin:/usr/local/lib'];
                        setenv('PATH', result);
                    end
                else
                    [elastixDir, ~] = fileparts(which('elastix.exe'));
                    result = getenv('PATH');
                    if ~contains(result,elastixDir)
                        result = [result ';' elastixDir];
                        setenv('PATH', result);
                    end
                end
            catch
            end

            % Trick to automatically include the exe and dll file during compilation
            try
                if ispc
                    import('elastix.exe');
                end
            catch
            end
            try
                if ispc
                    import('ANNlib-5.1.dll');
                end
            catch
            end

        end

        % Button pushed function: LoadMRDfileButton
        function LoadDicomFileButtonPushed(app, event)
            
            % Indicate busy
            app.SetLights([0,0,0,0,0,0]);
            app.SetButtons([0,0,0,0,0,0,0]);
            app.SetFitButtons(0);
            app.SetImageSelectionButtons(0);
            app.SetSegmentationButtons(0);
            app.SetStatus(3);
            
            % Initialize flags
            app.validDataFlag = false;
            app.validRecoFlag = false;
            app.validFitFlag = false;
            app.validGradFlag = false;
            app.roiFlag = false;

            try

                % Ask for dicom import directory
                app.ADCmapUIFigure.Visible = 'off';
                app.dicomImportPath = uigetdir(app.dicomImportPath,'Dicom import path');
                app.ADCmapUIFigure.Visible = 'on';
                drawnow;

                % Check for user cancle
                if isempty(app.dicomImportPath)
                    app.dicomImportPath = '';
                    ME = MException('ADCmapp:userCancel','ERROR: Please select a valid directory ...');
                    throw(ME);
                end

                % Directory tag
                app.tag = strsplit(app.dicomImportPath,filesep);
                app.tag = app.tag(end);
                app.tag = app.tag{1};

                % List of dicom file names
                flist = dir(fullfile(app.dicomImportPath,'*.dcm'));

                % Check if DICOM data is found
                if isempty(flist)
                    ME = MException('ADCmapp:noDICOMdata','ERROR: No DICOM files found ...');
                    throw(ME);
                end

                % Read dicom images and info
                app.TextMessage('Reading Dicom images and tags ...');
                app.dcmInfo = {};
                app.data = [];
                for i = 1:length(flist)
                    app.dcmInfo{i} = dicominfo([app.dicomImportPath,filesep,flist(i).name]);
                    app.data(i,:,:) = dicomread([app.dicomImportPath,filesep,flist(i).name]);
                end
                app.DicomDirViewField.Value = app.dicomImportPath;

                % Permute x and y dimensions
                if ndims(app.data) == 3
                    app.data = permute(flip(app.data,2),[1 3 2]);
                end

                % Make a list of the b-values and gradient directions, if exist
                app.bValueInfo = {};
                if isfield(app.dcmInfo{1},'DiffusionBValue') && isfield(app.dcmInfo{1},'DiffusionGradientOrientation')
                    for i = 1:length(flist)
                        app.bValueInfo{i}.indx = i;
                        app.bValueInfo{i}.bvalue = app.dcmInfo{i}.DiffusionBValue;
                        app.bValueInfo{i}.grads = app.dcmInfo{i}.DiffusionGradientOrientation;
                        app.bValueInfo{i}.positions = app.dcmInfo{i}.ImagePositionPatient;
                    end

                    % Check whether there are at least 2 b-values in this acquisition
                    app.binfo = cell2mat(app.bValueInfo);
                    uniquebvalues = unique([app.binfo.bvalue]);
                    if length(uniquebvalues) > 1
                        app.TextMessage(strcat('B-values = ',{' '},num2str(uniquebvalues)));
                        app.TextMessage(strcat('Gradient direction = ',{' '},num2str(unique([app.binfo.grads]','rows'))));
                    else
                        ME = MException('ADCmapp:notEnoughBvalues','ERROR: Data needs to contain at least 2 b-values ...');
                        throw(ME);
                    end
                else
                    ME = MException('ADCmapp:noDiffusionData','ERROR: Data does not seem to contain diffusion-weighted images ...');
                    throw(ME);
                end

                % Success
                app.validDataFlag = true;
                app.validGradFlag = true;
                app.SetParameters;
                
                % Set buttons
                app.SetLights([1,0,0,0,0,0]);
                app.SetButtons([1,1,1,1,0,0,1]);
                app.SetImageSelectionButtons(1);
                app.SetStatus(0);
                                
                % Show the image
                app.PlotImageFig;
                app.PlotMaps;

            catch ME

                % Failure
                app.validDataFlag = false;
                app.TextMessage(ME.message);

                % Set status
                app.SetLights([0,0,0,0,0,0]);
                app.SetButtons([1,0,0,0,0,0,1]);
                app.SetStatus(2);

            end
            
        end

        % Value changed function: TEViewField
        function TEViewFieldValueChanged(app, event)
            
            app.te = app.TEViewField.Value;
            app.parameters.te = app.TEViewField.Value;
            app.esp = app.ESPEditField.Value;
            app.tes = app.te + app.esp*linspace(0,app.ne-1,app.ne)'; % echo times
            app.TextMessage(strcat('Echo times = ',{' '},num2str(app.tes')));
            
        end

        % Value changed function: ImageEditField, SlicesEditField
        function SlicesEditFieldValueChanged(app, event)
            
            % Adjust the slider
            app.SlicesSlider.Value = app.SlicesEditField.Value;
            app.ImageSlider.Value = app.ImageEditField.Value;
            
            indx = app.SlicesEditField.Value + app.ns*(app.ImageEditField.Value - 1);
            app.BvalueViewField.Value = num2str(app.binfo(indx).bvalue);
            app.GradViewField.Value = num2str(app.binfo(indx).grads');
        
            % Plot the raw image 
            PlotImageFig(app);
            
        end

        % Value changed function: SlicesSlider
        function SlicesSliderValueChanged(app, event)
            
            % Adjust the slices edit field value
            app.SlicesEditField.Value = round(app.SlicesSlider.Value);
            
            % Continue with the changed slices edit field callback
            SlicesEditFieldValueChanged(app, event)
            
        end

        % Value changed function: ImageSlider
        function ImageSliderValueChanged(app, event)
            
            % Adjust the echoes edit field value
            app.ImageEditField.Value = round(app.ImageSlider.Value);
            
            % Continue with the changed slices / echoes edit field callback
            SlicesEditFieldValueChanged(app, event)
            
        end

        % Button pushed function: ImportGradTableButton
        function ImportGradTableButtonPushed(app, event)
            
            % Valid file
            app.validGradFlag = false;
            
            try

                % Ask for file
                app.ADCmapUIFigure.Visible = 'off';
                [gradfile,app.gradPath] = uigetfile([app.gradPath,filesep,'*.txt']);
                app.ADCmapUIFigure.Visible = 'on';
                drawnow;

                % Check if file was selected
                if ~ischar(gradfile)
                    app.gradPath = '';
                    ME = MException('ADCmapp:userCancel',"ERROR: No gradient.txt file selected ...");
                    throw(ME);
                end

                % Load the gradient file
                btable = dlmread([app.gradPath,gradfile]); %#ok<DLMRD>

                % Check if number of provided b-values and gradient directions could be correct
                if ~isequal(size(btable),[app.nr 4])
                    ME = MException('ADCmapp:bvaluesNotCorrect',"ERROR: Number of gradient directions and b-values does not match data ...");
                    throw(ME);
                end
           
                % Extract b-values and gradient directions
                bvalues = btable(:,1);
                gvalues = btable(:,2:4);

                % Check if b-values>0 and gradient directions between -1 and 1
                if (min(bvalues(:)) < 0) || (min(gvalues(:) < -1) || (max(gvalues(:)) > 1))
                    ME = MException('ADCmapp:bvaluesNumbersNotCorrect',"ERROR: B-values should be > 0 ; gradient directions should be between -1 and 1 ...");
                    throw(ME);
                end

                % All seems well, put the new b-values and gradient directions in the DICOM info
                cnt = 1;
                for j = 1:app.nr
                    for i = 1:app.ns
                        app.binfo(cnt).bvalue = bvalues(j);
                        app.binfo(cnt).grads = gvalues(j,:)';
                        cnt = cnt + 1;
                    end
                end

                % Success
                app.validGradFlag = true;
                app.TextMessage('Import of b-value & gradient table successful ...');
                app.SetStatus(0);
                
                app.TextMessage(strcat('B-values = ',{' '},num2str(unique([app.binfo.bvalue]))));
                app.TextMessage(strcat('Gradient direction = ',{' '},num2str(unique([app.binfo.grads]','rows'))));

            catch ME

                % Failure
                app.validGradFlag = false;
                app.TextMessage(ME.message);
                app.SetStatus(2);
   
            end
   
        end

        % Button pushed function: MaskButton
        function MaskButtonPushed(app, event)
            
            if app.validRecoFlag
                
                % Indicate busy
                app.SetLights([1,2,0,0,0,0]);
                app.SetButtons([0,0,0,0,0,0,0]);
                app.SetFitButtons(0);
                app.SetImageSelectionButtons(0);
                app.SetSegmentationButtons(0);
                app.SetStatus(3);
                app.TextMessage('Thresholding images ....');

                try

                    % Determine automatic threshold using the Otsu method for each slice
                    for i=1:app.ns
                        app.threshold(i) = graythresh(mat2gray(app.images(1,i,:,:))) * max(squeeze(app.images(1,i,:)));
                    end

                    % Set the automatic mask for all slices
                    app.mask = ones(app.ns,app.dimx,app.dimy);
                    for i=1:app.ns
                        app.mask(i,:,:) = apply_threshold_adc(squeeze(app.images(1,i,:,:)),app.threshold(i));
                    end

                    % Display the threshold value for the current slice
                    app.ThresholdEditField.Value = app.threshold(app.SlicesEditField.Value);

                    % Display the mask in the image
                    PlotImageFig(app);

                catch ME

                    app.TextMessage(ME.message);

                end
                
                % Indicate busy
                app.SetLights([1,2,1,0,0,0]);
                app.SetButtons([1,1,1,1,0,0,1]);
                app.SetFitButtons(1);
                app.SetImageSelectionButtons(1);
                app.SetSegmentationButtons(1);
                app.SetStatus(0);
                
            end
            
        end

        % Value changed function: ThresholdEditField
        function ThresholdEditFieldValueChanged(app, event)
            
            % Get the current slice
            cslice = app.SlicesEditField.Value;
            
            % Change the value of the threshold for this slice and update the slider
            app.threshold(cslice) = app.ThresholdEditField.Value;
            app.ThresholdSlider.Value = app.ThresholdEditField.Value;

            % Apply the new threshold
            try
                app.mask(cslice,:,:) = apply_threshold_adc(squeeze(app.images(1,cslice,:,:)),app.threshold(cslice));
            catch ME
                app.TextMessage(ME.message);
            end

            % Display the result;
            PlotImageFig(app)

        end

        % Value changed function: ThresholdSlider
        function ThresholdSliderValueChanged(app, event)
            
            % Threshold slider changed
            % Adjust Threshold and call threshold change button
            app.ThresholdEditField.Value = app.ThresholdSlider.Value;
            ThresholdEditFieldValueChanged(app, event);
            
        end

        % Button pushed function: ErodeButton
        function ErodeButtonPushed(app, event)
            
            % Morphological erosion on current slice
            try
                
                app.mask(app.SlicesEditField.Value,:,:) = 1-imerode(1-squeeze(app.mask(app.SlicesEditField.Value,:,:)),app.structuringElement2D);

                % Display the result;
                PlotImageFig(app);

            catch ME
                
                app.TextMessage(ME.message);
                
            end
            
        end

        % Button pushed function: DilateButton
        function DilateButtonPushed(app, event)
        
            % Morphological dilation on current slice
            
            try
                
                app.mask(app.SlicesEditField.Value,:,:) = 1-imdilate(1-squeeze(app.mask(app.SlicesEditField.Value,:,:)),app.structuringElement2D);

                % Display the result;
                PlotImageFig(app);

            catch ME

                app.TextMessage(ME.message);

            end
            
        end

        % Button pushed function: CloseButton
        function CloseButtonPushed(app, event)
            
            try

                % Morphological dilation on current slice
                app.mask(app.SlicesEditField.Value,:,:) = 1-imdilate(1-squeeze(app.mask(app.SlicesEditField.Value,:,:)),app.structuringElement2D);
                app.mask(app.SlicesEditField.Value,:,:) = 1-imerode(1-squeeze(app.mask(app.SlicesEditField.Value,:,:)),app.structuringElement2D);

                % Display the result;
                PlotImageFig(app);

            catch ME

                app.TextMessage(ME.message);

            end
            
        end

        % Button pushed function: OpenButton
        function OpenButtonPushed(app, event)
            
            try

                % Morphological dilation on current slice
                app.mask(app.SlicesEditField.Value,:,:) = 1-imopen(1-squeeze(app.mask(app.SlicesEditField.Value,:,:)),app.structuringElement2D);

                % Display the result;
                PlotImageFig(app);

            catch ME

                app.TextMessage(ME.message);

            end
            
        end

        % Value changed function: WindowEditField
        function WindowEditFieldValueChanged(app, event)
             
            app.WindowSlider.Value = app.WindowEditField.Value;
            app.AutoLWButton.Value = 0;
            app.PlotImageFig;
            
        end

        % Value changed function: WindowSlider
        function WindowSliderValueChanged(app, event)

            app.WindowEditField.Value = round(app.WindowSlider.Value);
            app.AutoLWButton.Value = 0;
            app.PlotImageFig;
            
        end

        % Value changed function: LevelEditField
        function LevelEditFieldValueChanged(app, event)
            
            app.LevelSlider.Value = app.LevelEditField.Value;
            app.AutoLWButton.Value = 0;
            app.PlotImageFig;
            
        end

        % Value changed function: LevelSlider
        function LevelSliderValueChanged(app, event)
            
            app.LevelEditField.Value = round(app.LevelSlider.Value);
            app.AutoLWButton.Value = 0;
            app.PlotImageFig;
            
        end

        % Value changed function: AutoLWButton
        function AutoLWButtonValueChanged(app, event)
            
            if app.AutoLWButton.Value == 1
                app.PlotImageFig;
            end
            
        end

        % Button pushed function: RegisterButton
        function RegisterButtonPushed(app, event)
            
            if app.validRecoFlag

                % Indicate busy
                app.SetLights([1,0,0,0,0,0]);
                app.SetButtons([0,0,0,0,0,0,0]);
                app.SetFitButtons(0);
                app.SetImageSelectionButtons(0);
                app.SetSegmentationButtons(0);
                app.SetStatus(3);
                app.validFitFlag = false;
                app.validRegFlag = false;
                app.AbortRegButton.Enable = 'on';
                app.abortRegFlag = false;
                
                try

                    % Register all slices to unweighted image
                    registerImages(app);

                    % reset m0 and ADCmap
                    app.m0map = zeros(app.ns,app.dimx,app.dimy);
                    app.adcmap = zeros(app.ns,app.dimx,app.dimy);
                    app.r2map = zeros(app.ns,app.dimx,app.dimy);
                    app.mask = ones(app.ns,app.dimx,app.dimy);

                    % replot the images
                    app.PlotImageFig;

                    % Reset buttons
                    app.SetLights([1,1,0,0,0,0]);
                    app.SetButtons([1,1,1,1,0,0,1]);
                    app.SetImageSelectionButtons(1);
                    app.AbortRegButton.Enable = 'off';
                    app.SetStatus(0);
             
                catch ME

                    app.TextMessage(ME.message);
                    app.TextMessage('ERROR: Something went wrong during registration ...');
                    app.SetLights([1,0,0,0,0,0]);
                    app.SetButtons([1,1,1,1,0,0,1]);
                    app.SetImageSelectionButtons(1);
                    app.AbortRegButton.Enable = 'off';
                    app.SetStatus(2);
             
                end

            end

        end

        % Button pushed function: AbortRegButton
        function AbortRegButtonPushed(app, event)
            
              app.abortRegFlag = true;  

        end

        % Button pushed function: FitADCButton
        function FitADCButtonPushed(app, event)
            
            if app.validRecoFlag

                app.SetLights([2,2,2,0,0,0]);
                app.SetButtons([0,0,0,0,0,0,0]);
                app.SetFitButtons(0);
                app.SetImageSelectionButtons(0);
                app.SetSegmentationButtons(0);
                app.SetStatus(3);
                app.TextMessage('Fitting ADC maps ....');

                app.validFitFlag = false;
                app.abortFitFlag = false;
                app.AbortFitButton.Enable = 'on';

                try

                    % Check if suitable gradient and b-value information is available
                    if ~app.validGradFlag
                        ME = MException('ADCmapp:noValidGradients','ERROR: Missing gradient and b-value information ...');
                        throw(ME);
                    end

                    % Organize the b-values and gradient directions
                    app.SelectData;

                    % Check for sufficient data points
                    if nnz(app.nrSelection) < 2
                        ME = MException('ADCmapp:notEnoughData','ERROR: ADC fitting needs at least 2 datapoints ...');
                        throw(ME);
                    end

                    % Progress counter reset
                    cnt = 1;
                    totalNumberOfSteps = app.LastSliceEditField.Value - app.FirstSliceEditField.Value + 1;

                    % Timing parameters
                    app.EstimatedFitTimeViewField.Value = 'Calculating ...';
                    elapsedTime = 0;

                    slice = app.FirstSliceEditField.Value - 1;

                    % for selected slices
                    while slice < app.LastSliceEditField.Value && ~app.abortFitFlag

                        slice = slice + 1;

                        tic;

                        % Do the fit for all coordinates, parallel accelerated
                        [app.m0map(slice,:,:),app.adcmap(slice,:,:),app.r2map(slice,:,:)] = dotheADCfit(app,squeeze(app.images(:,slice,:,:)),squeeze(app.mask(slice,:,:)));
                     
                        % Determine the scale
                        try
                            scale = round(2*mean(nonzeros(abs(app.adcmap(:).*app.mask(:)))));
                            scale(scale<0.1) = 0.1;
                            app.ADCScaleEditField.Value = scale;
                        catch
                            scale = 3;
                            app.ADCScaleEditField.Value = scale;
                        end

                        % Plot the maps
                        app.validFitFlag = true;
                        PlotMaps(app);
                        app.validFitFlag = false;

                        % Update progresss bar
                        app.FitProgressGauge.Value = round(100 * cnt/totalNumberOfSteps);
                        drawnow;

                        % Update the timing indicator
                        elapsedTime = elapsedTime + toc;
                        estimatedtotaltime = elapsedTime * totalNumberOfSteps / cnt;
                        timeRemaining = estimatedtotaltime * (totalNumberOfSteps - cnt) / totalNumberOfSteps;
                        timeRemaining(timeRemaining<0) = 0;
                        app.EstimatedFitTimeViewField.Value = strcat(datestr(seconds(timeRemaining),'MM:SS')," min:sec"); %#ok<*DATST>
                        drawnow;

                        cnt = cnt + 1;

                    end

                    app.TextMessage('Finished ... ');
                    app.EstimatedFitTimeViewField.Value = 'Finished ...';

                    % Reset buttons
                    app.SetLights([2,2,2,1,0,0]);
                    app.SetButtons([1,1,1,1,1,1,1]);
                    app.SetFitButtons(1);
                    app.SetImageSelectionButtons(1);
                    app.SetSegmentationButtons(1);
                    app.AbortFitButton.Enable = 'off';
                    app.SetStatus(0);
                    app.validFitFlag = true;
                    

                catch ME

                    app.TextMessage(ME.message);
                  
                    % Reset buttons
                    app.SetLights([2,2,2,0,0,0]);
                    app.SetButtons([1,1,1,1,1,1,1]);
                    app.SetFitButtons(1);
                    app.SetImageSelectionButtons(1);
                    app.SetSegmentationButtons(1);
                    app.AbortFitButton.Enable = 'off';
                    app.SetStatus(2);
                    app.validFitFlag = false;

                end

            end

        end

        % Button pushed function: AbortFitButton
        function AbortFitButtonPushed(app, event)
            
            app.abortFitFlag = true; 
        
        end

        % Value changed function: FirstSliceEditField, LastSliceEditField
        function FirstSliceEditFieldValueChanged(app, event)
            
            if app.FirstSliceEditField.Value > app.LastSliceEditField.Value
                app.LastSliceEditField.Value = app.FirstSliceEditField.Value;
            end
            
        end

        % Value changed function: ADCMapSlicesEditField
        function ADCMapSlicesEditFieldValueChanged(app, event)
            
            % Change the slider value according to the edit number
            app.ADCMapSlicesSlider.Value = round(app.ADCMapSlicesEditField.Value);
            app.ADCMapSlicesEditField2.Value = app.ADCMapSlicesEditField.Value;
            app.ADCMapSlicesSlider2.Value = app.ADCMapSlicesSlider.Value;
            
            % Plot the ADC-map for the new slice
            PlotMaps(app);
            
        end

        % Value changed function: ADCMapSlicesEditField2
        function ADCMapSlicesEditField2ValueChanged(app, event)
            
            % Change the slider value according to the edit number
            app.ADCMapSlicesSlider2.Value = round(app.ADCMapSlicesEditField2.Value);
            app.ADCMapSlicesEditField.Value = app.ADCMapSlicesEditField2.Value;
            app.ADCMapSlicesSlider.Value = app.ADCMapSlicesSlider2.Value;
            
            % Plot the ADC-map for the new slice
            PlotMaps(app);
            
        end

        % Value changed function: ADCMapSlicesSlider
        function ADCMapSlicesSliderValueChanged(app, event)
            
            % Change the Slice nr value according to the slider
            app.ADCMapSlicesEditField.Value = round(app.ADCMapSlicesSlider.Value);
            app.ADCMapSlicesEditField2.Value = round(app.ADCMapSlicesSlider.Value);
            app.ADCMapSlicesSlider2.Value = app.ADCMapSlicesSlider.Value;
            
            % Plot the ADC-map for the new slice
            PlotMaps(app);
            
        end

        % Value changed function: ADCMapSlicesSlider2
        function ADCMapSlicesSlider2ValueChanged(app, event)
            
            % Change the Slice nr value according to the slider
            app.ADCMapSlicesEditField2.Value = round(app.ADCMapSlicesSlider2.Value);
            app.ADCMapSlicesEditField.Value = round(app.ADCMapSlicesSlider2.Value);
            app.ADCMapSlicesSlider.Value = app.ADCMapSlicesSlider2.Value;
            
            % Plot the ADC-map for the new slice
            PlotMaps(app);
            
        end

        % Value changed function: ADCScaleEditField
        function ADCScaleEditFieldValueChanged(app, event)
            
            % Plot the ADC map with the new scale
            PlotMaps(app);
            
        end

        % Button pushed function: ADCScaleAutoButton
        function ADCScaleAutoButtonPushed(app, event)
            
            % Estimate a suitable scale for the ADC map
            % Auto-scale = 2 x mean of the ADC values in the image
            scale = round(2*mean(nonzeros(squeeze(app.adcmap(app.ADCMapSlicesEditField.Value,:))))); 
            scale(isnan(scale)) = 3;
            scale(scale<0.1) = 0.1;
            app.ADCScaleEditField.Value = scale;
            
            % Plot the map
            PlotMaps(app);
            
        end

        % Selection changed function: ColormapButtonGroup
        function ColormapButtonGroupSelectionChanged2(app, event)
            
            if app.grayButton.Value == 1 
                app.adccmap = gray; 
            end
            
            if app.autumnButton.Value == 1
                app.adccmap = autumn; 
            end
            
            if app.winterButton.Value == 1
                app.adccmap = winter; 
            end
            
            if app.parulaButton.Value == 1
                app.adccmap = parula; 
            end
            
            if app.hotButton.Value == 1
                app.adccmap = hot; 
            end
            
            % Background should always be black
            app.adccmap(1,:) = [0 0 0];
            
            % Plot the ADC map with the new colormap
            PlotMaps(app);
            
        end

        % Selection changed function: ColorMapButtonGroup2
        function ColorMapButtonGroup2SelectionChanged(app, event)
            
            if app.grayButton2.Value == 1 
                app.m0cmap = gray; 
            end
            
            if app.autumnButton2.Value == 1
                app.m0cmap = autumn; 
            end
            
            if app.winterButton2.Value == 1
                app.m0cmap = winter; 
            end
            
            if app.parulaButton2.Value == 1
                app.m0cmap = parula; 
            end
            
            if app.hotButton2.Value == 1
                app.m0cmap = hot; 
            end
            
            % Background should always be black
            app.m0cmap(1,:) = [0 0 0];
            
            % Plot the M0 map with the new colormap
            PlotMaps(app);
            
        end

        % Selection changed function: ColorMapButtonGroup3
        function ColorMapButtonGroup3SelectionChanged(app, event)
              
            if app.grayButton3.Value == 1 
                app.r2cmap = gray; 
            end
            
            if app.autumnButton3.Value == 1
                app.r2cmap = autumn; 
            end
            
            if app.winterButton3.Value == 1
                app.r2cmap = winter; 
            end
            
            if app.parulaButton3.Value == 1
                app.r2cmap = parula; 
            end
            
            if app.hotButton3.Value == 1
                app.r2cmap = hot; 
            end
            
            % Background should always be black
            app.r2cmap(1,:) = [0 0 0];
            
            % Plot the R2 map with the new colormap
            PlotMaps(app);
            
            
        end

        % Image clicked function: ADCImage
        function ADCImageClicked(app, event)
            
            if app.validFitFlag

                % Get the Position of the GUI and the position of the mouse click
                set(0,'units','pixels');
                guiposition = get(app.ADCmapUIFigure,'Position');
                cursorposition = get(0,'PointerLocation');

                % Position of the cursor click in the image window
                imagepos = [862,412]; % position of bottom-left corner of image in the app (862,412)
                Xpos = cursorposition(1)-guiposition(1)-imagepos(1);
                Ypos = cursorposition(2)-guiposition(2)-imagepos(2);

                % Correct for non-square image
                aratio = app.AspectRatioViewField.Value;
                xsize = app.dimy;
                ysize = app.dimx;

                % Correct for non-square image
                imsize = 313;
                if aratio >= 1
                    xcor = (Xpos/imsize) * xsize;
                    ycor = (Ypos/imsize) * ysize * aratio - (0.5 * (aratio-1) * ysize);
                else
                    xcor = (Xpos/imsize) * xsize * (1/aratio) - (0.5 * ((1/aratio)-1) * xsize);
                    ycor = (Ypos/imsize) * ysize;
                end

                % Round to nearest integer
                xcor = round(xcor);
                ycor = round(ycor);

                % Current slice
                zcor = app.ADCMapSlicesEditField.Value;

                % Keep the values within matrix boundaries
                xcor(xcor<1) = 1;
                ycor(ycor<1) = 1;
                xcor(xcor>xsize) = xsize;
                ycor(ycor>ysize) = ysize;
                app.XEditField.Value = xcor;
                app.YEditField.Value = ycor;

                % ROI or cross-hair display
                if app.roiFlag

                    % Include points within Radius
                    se = strel("disk",app.BrushEditField.Value-1);
                    [nzrow,nzcol] = find(se.Neighborhood==1);
                    nzc = [nzrow,nzcol] - [app.BrushEditField.Value-1,app.BrushEditField.Value-1];

                    % Paint ROI
                    for i = 1:size(nzc,1)
                        if (xcor+nzc(i,1)>0) && (ycor+nzc(i,2)>0) && (xcor+nzc(i,1)<=xsize) && (ycor+nzc(i,2)<=ysize)
                            app.roiPoints = [app.roiPoints; [xcor+nzc(i,1) ycor+nzc(i,2) zcor]];
                        end
                    end
                    app.roiPoints = unique(app.roiPoints,'rows');
                    zColumn = app.roiPoints(:,3);
                    plotPoints = app.roiPoints(zColumn==zcor, :);
                    app.PlotPoint(plotPoints(:,1),plotPoints(:,2),ysize);

                else

                    % Plot a cross
                    app.roiPoints = [xcor ycor zcor];
                    app.PlotCross(app.roiPoints(1),app.roiPoints(2),ysize);

                end

                % Plot the results of the fits in the chosen pixel
                app.PlotFits;

            end

        end

        % Image clicked function: M0Image
        function M0ImageClicked(app, event)
            
              app.ADCImageClicked;  

        end

        % Button pushed function: ROIbutton, ROIbutton2
        function ROIbuttonButtonPushed(app, event)
            
            % Toggle between ROI drawing and cross-hair drawing
             
             if strcmp(app.ROIbutton.Text,'ROI')
                 
                 app.ROIbutton.Text = 'Cross';
                 app.ROIbutton2.Text = 'Cross';
                 app.TextMessage('ROI drawing selected ...');
                 app.EraseButton.Visible = 'on';
                 app.EraseButton2.Visible = 'on';
                 app.BrushEditField.Visible = 'on';
                 app.brushEditFieldLabel.Visible = 'on';
                 app.BrushEditField2.Visible = 'on';
                 app.brushEditField_2Label.Visible = 'on';
                 app.roiFlag = true;

             else

                 app.ROIbutton.Text = 'ROI';
                 app.ROIbutton2.Text = 'ROI';
                 app.TextMessage('Cross-hair selected ...');
                 app.EraseButton.Visible = 'off';
                 app.EraseButton2.Visible = 'off';
                 app.BrushEditField.Visible = 'off';
                 app.brushEditFieldLabel.Visible = 'off';
                 app.BrushEditField2.Visible = 'off';
                 app.brushEditField_2Label.Visible = 'off';
                 app.roiFlag = false;
                 
             end
            
             app.roiPoints = [];
             delete(app.roiMD);
             delete(app.roiM0);
             delete(app.crossMD);
             delete(app.crossM0);
             app.PlotMaps;

        end

        % Value changed function: BrushEditField
        function BrushEditFieldValueChanged(app, event)
            
            app.BrushEditField2.Value = app.BrushEditField.Value;
            
        end

        % Value changed function: BrushEditField2
        function BrushEditField2ValueChanged(app, event)
            
            app.BrushEditField.Value = app.BrushEditField2.Value;
            
        end

        % Button pushed function: EraseButton, EraseButton2
        function EraseButtonPushed(app, event)
                       
            app.roiPoints = [];
            delete(app.roiMD);
            delete(app.roiM0);
            app.PlotMaps;

        end

        % Button pushed function: ExportDCMButton
        function ExportDCMButtonPushed(app, event)
            
            if app.validFitFlag

                % Indicate busy
                app.SetLights([2,2,2,2,0,2]);
                app.SetButtons([0,0,0,0,0,0,0]);
                app.SetFitButtons(0);
                app.SetImageSelectionButtons(0);
                app.SetSegmentationButtons(0);
                app.SetStatus(3);

                try

                    app.TextMessage('DICOM export ...');

                    % Base / Data / 343 / DICOM / 8455 /
                    dcmSpath{1} = app.dicomImportPath;
                    try
                        k = strfind(dcmSpath{1},"Data");
                        fs = strfind(dcmSpath{1}(k:end),filesep);
                        dcmSpath{1}= dcmSpath{1}(1:k+fs(3)-1);
                        dcmSpath{1} = insertAfter(dcmSpath{1},k+fs(2)-1,strcat("DICOM",filesep));
                        dcmIndx{1} = 2;
                    catch
                        dcmSpath{1} = app.mrdImportPath;
                    end

                    % Base / data / 343 / DICOM / 8455 / 1 /
                    dcmSpath{2} = strcat(dcmSpath{1},'1',filesep);
                    dcmIndx{2} = 3;

                    % Base / data / 343 / DICOM / 8455 / 2 /
                    dcmSpath{3} = strcat(dcmSpath{1},'2',filesep);
                    dcmIndx{3} = 3;

                    % Base / data / 343 / DICOM / 8455 / 3 /
                    dcmSpath{4} = strcat(dcmSpath{1},'3',filesep);
                    dcmIndx{4} = 3;

                    % Check if one of the directories contains dicom files
                    dcmExist = false;
                    for i=1:length(dcmSpath)
                        if ~isempty(dir(strcat(dcmSpath{i},"*.dcm")))
                            dcmPath = dcmSpath{i};
                            app.TextMessage(strcat("Found DICOM information in ",dcmPath));
                            filesepIdx = strfind(dcmPath, filesep);
                            app.dicomExportPath = dcmPath(1:filesepIdx(end-dcmIndx{i}));
                            dcmExist = true;
                            break;
                        end
                    end

                    % Export to DICOM
                    if dcmExist

                        try
                            % Check if directory is actually writable
                            mkdir(strcat(app.dicomExportPath,filesep,"testdir"));
                            rmdir(strcat(app.dicomExportPath,filesep,"testdir"));
                            app.TextMessage(strcat("DICOM export directory = ",app.dicomExportPath));
                            exportDCMadc(app, app.dicomExportPath);
                        catch
                            app.ADCmapUIFigure.Visible = 'off';
                            app.dicomExportPath = uigetdir(app.dicomExportPath,'DICOM export path');
                            app.ADCmapUIFigure.Visible = 'on';
                            drawnow;
                            if ~ischar(app.dicomExportPath)
                                ME = MException('ADCmapp:userCancel','User cancel ...');
                                throw(ME);
                            end
                            dcmExportPath = strcat(app.dicomExportPath,filesep,"DICOM",filesep);
                            app.TextMessage(strcat("DICOM export directory = ",dcmExportPath));
                            exportDCMadc(app, dcmExportPath);
                        end

                    else

                        % Ask for dicom export directory
                        app.ADCmapUIFigure.Visible = 'off';
                        app.dicomExportPath = uigetdir(app.dicomExportPath,'DICOM export path');
                        app.ADCmapUIFigure.Visible = 'on';
                        drawnow;
                        if ~ischar(app.dicomExportPath)
                            ME = MException('ADCmapp:userCancel','User cancel ...');
                            throw(ME);
                        end
                        dcmExportPath = strcat(app.dicomExportPath,filesep,"DICOM",filesep);
                        app.TextMessage(strcat("DICOM export directory = ",dcmExportPath));
                        exportDCMadc(app, dcmExportPath);

                    end

                    % Success
                    app.TextMessage('DICOM export ready ...');
                    app.SetLights([2,2,2,2,1,2]);
                    app.SetButtons([1,1,1,1,1,1,1]);
                    app.SetFitButtons(1);
                    app.SetImageSelectionButtons(1);
                    app.SetSegmentationButtons(1);
                    app.SetStatus(0);

                catch ME

                    % Failure
                    app.TextMessage(ME.message);
                    app.TextMessage('ERROR: Something went wrong during DICOM export ...');
                    app.dicomExportPath = '';
                    app.SetStatus(2);
                    app.SetLights([2,2,2,2,0,2]);
                    app.SetButtons([1,1,1,1,1,1,1]);
                    app.SetFitButtons(1);
                    app.SetImageSelectionButtons(1);
                    app.SetSegmentationButtons(1);
                    
                end

            end
        
        end

        % Button pushed function: ExportGIFButton
        function ExportGIFButtonPushed(app, event)
         
            if app.validFitFlag

                % Indicate busy
                app.SetLights([2,2,2,2,2,0]);
                app.SetButtons([0,0,0,0,0,0,0]);
                app.SetFitButtons(0);
                app.SetImageSelectionButtons(0);
                app.SetSegmentationButtons(0);
                app.SetStatus(3);

                % Export to animated gif
                try

                    app.TextMessage('GIF export ...');

                    try
                        app.gifExportPath = replace(app.mrdImportPath, "MRD", "Data");
                        k = strfind(app.gifExportPath,"Data");
                        fs = strfind(app.gifExportPath(k:end),filesep);
                        app.gifExportPath = app.gifExportPath(1:k+fs(2)-1);
                        % Check if directory is actually writable
                        mkdir(strcat(app.gifExportPath,filesep,"testdir"));
                        rmdir(strcat(app.gifExportPath,filesep,"testdir"));
                        gifPathExist = true;
                    catch
                        gifPathExist = false;
                    end

                    if ~gifPathExist
                        app.ADCmapUIFigure.Visible = 'off';
                        app.gifExportPath = uigetdir(app.gifExportPath,'GIF export path');
                        app.ADCmapUIFigure.Visible = 'on';
                        drawnow;
                        if ~ischar(app.gifExportPath)
                            ME = MException('Retrospective:userCancel','User cancel ...');
                            throw(ME);
                        end
                    end

                    gfExportPath = strcat(app.gifExportPath,filesep,"GIF",filesep);
                    app.TextMessage(strcat("GIF export directory = ",gfExportPath));

                    exportGIFadc(app, gfExportPath);
                
                    % Success
                    app.TextMessage('GIF export ready ...');
                    app.SetLights([2,2,2,2,2,1]);
                    app.SetButtons([1,1,1,1,1,1,1]);
                    app.SetFitButtons(1);
                    app.SetImageSelectionButtons(1);
                    app.SetSegmentationButtons(1);
                    app.SetStatus(0);

                catch ME

                    % Failure
                    app.TextMessage(ME.message);
                    app.TextMessage('ERROR: Something went wrong during GIF export ...');
                    app.gifExportPath = '';
                    app.SetStatus(2);
                    app.SetLights([2,2,2,2,2,0]);
                    app.SetButtons([1,1,1,1,1,1,1]);
                    app.SetFitButtons(1);
                    app.SetImageSelectionButtons(1);
                    app.SetSegmentationButtons(1);
                   
                end

            end
        
        end

        % Button pushed function: ExitButton
        function ExitButtonPushed(app, event)
         
            % Remove subfolders from search path
            if (~isdeployed)
                info = dir(which('ADCmapp.mlapp'));
                app.appdir = info.folder;
                rmpath(genpath(app.appdir));
                cd(app.olddir);
            end
            
            % Exit
            app.delete;
        
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.ADCmapUIFigure = uifigure('Visible', 'off');
            app.ADCmapUIFigure.Name = 'ADCmapp';
            app.ADCmapUIFigure.Icon = fullfile(pathToMLAPP, 'ADCicon.gif');
            app.ADCmappingLabel = uilabel(app.ADCmapUIFigure);
            app.ADCmappingLabel.Text = 'ADC mapping';
            app.Credits = uilabel(app.ADCmapUIFigure);
            app.Credits.Tooltip = {'g.j.strijkers@amsterdamumc.nl'};
            app.Credits.Text = 'Gustav Strijkers, Amsterdam UMC, Version X.X.X';
            app.TulpLogo = uiimage(app.ADCmapUIFigure);
            app.MessageBox = uitextarea(app.ADCmapUIFigure);
            app.MessageBox.Editable = 'off';
            app.MessageBox.Tooltip = {'Application messages'};
            app.MessageBox.Value = {'Messages ....'};
            app.BackgroundBox = uitextarea(app.ADCmapUIFigure);
            app.BackgroundBox.Editable = 'off';
            app.BackgroundBox.Tooltip = {'Application status'};
            app.StatusImage = uiimage(app.ADCmapUIFigure);
            app.statusLabel = uilabel(app.ADCmapUIFigure);
            app.statusLabel.Text = 'status';
            app.MRSImage = uiimage(app.ADCmapUIFigure);
            app.TabGroup1 = uitabgroup(app.ADCmapUIFigure);
            app.DashboardTab = uitab(app.TabGroup1);
            app.DashboardTab.Title = 'Dashboard';
            app.LoadMRDfileButton = uibutton(app.DashboardTab, 'push');
            app.LoadMRDfileButton.ButtonPushedFcn = createCallbackFcn(app, @LoadDicomFileButtonPushed, true);
            app.LoadMRDfileButton.Enable = 'off';
            app.LoadMRDfileButton.Tooltip = {'Load DICOM diffusion-weighted data'};
            app.LoadMRDfileButton.Text = 'Load DCM';
            app.Lamp1 = uilamp(app.DashboardTab);
            app.MaskButton = uibutton(app.DashboardTab, 'push');
            app.MaskButton.ButtonPushedFcn = createCallbackFcn(app, @MaskButtonPushed, true);
            app.MaskButton.Enable = 'off';
            app.MaskButton.Tooltip = {'Mask the images to exclude fitting of background pixels'};
            app.MaskButton.Text = 'Mask';
            app.Lamp3 = uilamp(app.DashboardTab);
            app.FitADCButton = uibutton(app.DashboardTab, 'push');
            app.FitADCButton.ButtonPushedFcn = createCallbackFcn(app, @FitADCButtonPushed, true);
            app.FitADCButton.Enable = 'off';
            app.FitADCButton.Tooltip = {'Start the ADC fitting'};
            app.FitADCButton.Text = 'Fit ADC';
            app.Lamp4 = uilamp(app.DashboardTab);
            app.FitProgressGauge = uigauge(app.DashboardTab, 'linear');
            app.FitProgressGauge.MajorTicks = [0 25 50 75 100];
            app.Lamp5 = uilamp(app.DashboardTab);
            app.ExitButton = uibutton(app.DashboardTab, 'push');
            app.ExitButton.ButtonPushedFcn = createCallbackFcn(app, @ExitButtonPushed, true);
            app.ExitButton.Enable = 'off';
            app.ExitButton.Tooltip = {'Exit the application'};
            app.ExitButton.Text = 'Exit';
            app.ExportDCMButton = uibutton(app.DashboardTab, 'push');
            app.ExportDCMButton.ButtonPushedFcn = createCallbackFcn(app, @ExportDCMButtonPushed, true);
            app.ExportDCMButton.Enable = 'off';
            app.ExportDCMButton.Tooltip = {'Export to DICOM'};
            app.ExportDCMButton.Text = 'Export DCM';
            app.FitProgressLabel = uilabel(app.DashboardTab);
            app.FitProgressLabel.Text = 'Progress';
            app.Lamp6 = uilamp(app.DashboardTab);
            app.ExportGIFButton = uibutton(app.DashboardTab, 'push');
            app.ExportGIFButton.ButtonPushedFcn = createCallbackFcn(app, @ExportGIFButtonPushed, true);
            app.ExportGIFButton.Enable = 'off';
            app.ExportGIFButton.Tooltip = {'Export to GIF'};
            app.ExportGIFButton.Text = 'Export GIF';
            app.AbortFitButton = uibutton(app.DashboardTab, 'push');
            app.AbortFitButton.ButtonPushedFcn = createCallbackFcn(app, @AbortFitButtonPushed, true);
            app.AbortFitButton.Enable = 'off';
            app.AbortFitButton.Tooltip = {'Abort the ADC fitting'};
            app.AbortFitButton.Text = 'Abort';
            app.RegisterButton = uibutton(app.DashboardTab, 'push');
            app.RegisterButton.ButtonPushedFcn = createCallbackFcn(app, @RegisterButtonPushed, true);
            app.RegisterButton.Enable = 'off';
            app.RegisterButton.Tooltip = {'Register images using Elastix or Matlab'};
            app.RegisterButton.Text = 'Register';
            app.Lamp2 = uilamp(app.DashboardTab);
            app.RegProgressGauge = uigauge(app.DashboardTab, 'linear');
            app.RegProgressGauge.MajorTicks = [0 25 50 75 100];
            app.RegProgressLabel = uilabel(app.DashboardTab);
            app.RegProgressLabel.Text = 'Progress';
            app.RegistrationDropDown = uidropdown(app.DashboardTab);
            app.RegistrationDropDown.Items = {'Translation', 'Rigid', 'Affine', 'B-Spline'};
            app.RegistrationDropDown.Enable = 'off';
            app.RegistrationDropDown.Tooltip = {'Image registration transformation method'};
            app.RegistrationDropDown.Value = 'Affine';
            app.AbortRegButton = uibutton(app.DashboardTab, 'push');
            app.AbortRegButton.ButtonPushedFcn = createCallbackFcn(app, @AbortRegButtonPushed, true);
            app.AbortRegButton.Enable = 'off';
            app.AbortRegButton.Tooltip = {'Abort the registration'};
            app.AbortRegButton.Text = 'Abort';
            app.EstimatedRegTimeViewField = uieditfield(app.DashboardTab, 'text');
            app.EstimatedRegTimeViewField.Editable = 'off';
            app.EstimatedFitTimeViewField = uieditfield(app.DashboardTab, 'text');
            app.EstimatedFitTimeViewField.Editable = 'off';
            app.TabGroup3 = uitabgroup(app.ADCmapUIFigure);
            app.ADCmapTab = uitab(app.TabGroup3);
            app.ADCmapTab.Title = 'ADC-map';
            app.ADCMapFig = uiaxes(app.ADCmapTab);
            app.ADCMapFig.XTick = [];
            app.ADCMapFig.YTick = [];
            app.ADClogPlot = uiaxes(app.ADCmapTab);
            title(app.ADClogPlot, 'Log')
            xlabel(app.ADClogPlot, 'b')
            ylabel(app.ADClogPlot, 'log ( Signal )')
            app.ADClinearPlot = uiaxes(app.ADCmapTab);
            title(app.ADClinearPlot, 'Linear')
            xlabel(app.ADClinearPlot, 'b')
            ylabel(app.ADClinearPlot, 'Signal')
            app.ADCMapLabel = uilabel(app.ADCmapTab);
            app.ADCMapLabel.Text = 'ADC map';
            app.ImageselectionPanel_2 = uipanel(app.ADCmapTab);
            app.ImageselectionPanel_2.Title = 'Image selection';
            app.ADCMapSlicesSlider = uislider(app.ImageselectionPanel_2);
            app.ADCMapSlicesSlider.Limits = [1 200];
            app.ADCMapSlicesSlider.MajorTicks = [];
            app.ADCMapSlicesSlider.ValueChangedFcn = createCallbackFcn(app, @ADCMapSlicesSliderValueChanged, true);
            app.ADCMapSlicesSlider.MinorTicks = [];
            app.ADCMapSlicesSlider.Tooltip = {'Slice number slider'};
            app.ADCMapSlicesSlider.Value = 1;
            app.SliceEditField_2Label = uilabel(app.ImageselectionPanel_2);
            app.SliceEditField_2Label.Text = 'Slice';
            app.ADCMapSlicesEditField = uieditfield(app.ImageselectionPanel_2, 'numeric');
            app.ADCMapSlicesEditField.Limits = [1 Inf];
            app.ADCMapSlicesEditField.ValueDisplayFormat = '%.0f';
            app.ADCMapSlicesEditField.ValueChangedFcn = createCallbackFcn(app, @ADCMapSlicesEditFieldValueChanged, true);
            app.ADCMapSlicesEditField.Tooltip = {'Slice number'};
            app.ADCMapSlicesEditField.Value = 1;
            app.ColormapButtonGroup = uibuttongroup(app.ADCmapTab);
            app.ColormapButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @ColormapButtonGroupSelectionChanged2, true);
            app.ColormapButtonGroup.Title = 'Color map';
            app.grayButton = uiradiobutton(app.ColormapButtonGroup);
            app.grayButton.Text = 'gray';
            app.autumnButton = uiradiobutton(app.ColormapButtonGroup);
            app.autumnButton.Text = 'autumn';
            app.winterButton = uiradiobutton(app.ColormapButtonGroup);
            app.winterButton.Text = 'winter';
            app.parulaButton = uiradiobutton(app.ColormapButtonGroup);
            app.parulaButton.Text = 'parula';
            app.parulaButton.Value = true;
            app.hotButton = uiradiobutton(app.ColormapButtonGroup);
            app.hotButton.Text = 'hot';
            app.ADCScaleEditField = uieditfield(app.ColormapButtonGroup, 'numeric');
            app.ADCScaleEditField.Limits = [0.1 Inf];
            app.ADCScaleEditField.ValueDisplayFormat = '%.2f';
            app.ADCScaleEditField.ValueChangedFcn = createCallbackFcn(app, @ADCScaleEditFieldValueChanged, true);
            app.ADCScaleEditField.Tooltip = {'ADC map scale'};
            app.ADCScaleEditField.Value = 1;
            app.ADCScaleMaxLabel = uilabel(app.ColormapButtonGroup);
            app.ADCScaleMaxLabel.Text = 'Max';
            app.ADCScaleAutoButton = uibutton(app.ColormapButtonGroup, 'push');
            app.ADCScaleAutoButton.ButtonPushedFcn = createCallbackFcn(app, @ADCScaleAutoButtonPushed, true);
            app.ADCScaleAutoButton.Tooltip = {'Automatic ADC map scale setting'};
            app.ADCScaleAutoButton.Text = 'Auto';
            app.ADCImage = uiimage(app.ADCmapTab);
            app.ADCImage.ImageClickedFcn = createCallbackFcn(app, @ADCImageClicked, true);
            app.XEditFieldLabel = uilabel(app.ADCmapTab);
            app.XEditFieldLabel.Text = 'X';
            app.XEditField = uieditfield(app.ADCmapTab, 'numeric');
            app.XEditField.ValueDisplayFormat = '%.0f';
            app.XEditField.Editable = 'off';
            app.XEditField.Tooltip = {'X-coordinate'};
            app.YEditFieldLabel = uilabel(app.ADCmapTab);
            app.YEditFieldLabel.Text = 'Y';
            app.YEditField = uieditfield(app.ADCmapTab, 'numeric');
            app.YEditField.ValueDisplayFormat = '%.0f';
            app.YEditField.Editable = 'off';
            app.YEditField.Tooltip = {'Y-coordinate'};
            app.ADCViewField = uieditfield(app.ADCmapTab, 'numeric');
            app.ADCViewField.ValueDisplayFormat = '%.2f';
            app.ADCViewField.Editable = 'off';
            app.ADCViewField.Tooltip = {'ADC value'};
            app.ADCLabel = uilabel(app.ADCmapTab);
            app.ADCLabel.Text = 'ADC ';
            app.ADCunitLabel1 = uilabel(app.ADCmapTab);
            app.ADCunitLabel1.Text = '10  mm /s';
            app.ADCunitLabel2 = uilabel(app.ADCmapTab);
            app.ADCunitLabel2.Text = '-3';
            app.ADCunitLabel3 = uilabel(app.ADCmapTab);
            app.ADCunitLabel3.Text = '2';
            app.ROIbutton = uibutton(app.ADCmapTab, 'push');
            app.ROIbutton.ButtonPushedFcn = createCallbackFcn(app, @ROIbuttonButtonPushed, true);
            app.ROIbutton.Tooltip = {'Cross-hair / ROI drawing'};
            app.ROIbutton.Text = 'ROI';
            app.EraseButton = uibutton(app.ADCmapTab, 'push');
            app.EraseButton.ButtonPushedFcn = createCallbackFcn(app, @EraseButtonPushed, true);
            app.EraseButton.Visible = 'off';
            app.EraseButton.Tooltip = {'Erase ROI'};
            app.EraseButton.Text = 'Erase';
            app.brushEditFieldLabel = uilabel(app.ADCmapTab);
            app.brushEditFieldLabel.Visible = 'off';
            app.brushEditFieldLabel.Text = 'brush';
            app.BrushEditField = uieditfield(app.ADCmapTab, 'numeric');
            app.BrushEditField.Limits = [1 5];
            app.BrushEditField.ValueDisplayFormat = '%.0f';
            app.BrushEditField.ValueChangedFcn = createCallbackFcn(app, @BrushEditFieldValueChanged, true);
            app.BrushEditField.Visible = 'off';
            app.BrushEditField.Tooltip = {'ROI drawing brush size (pixels)'};
            app.BrushEditField.Value = 2;
            app.M0R2Tab = uitab(app.TabGroup3);
            app.M0R2Tab.Title = 'M0 & R^2';
            app.M0MapFig = uiaxes(app.M0R2Tab);
            app.M0MapFig.XTick = [];
            app.M0MapFig.YTick = [];
            app.R2MapFig = uiaxes(app.M0R2Tab);
            app.R2MapFig.XTick = [];
            app.R2MapFig.YTick = [];
            app.M0MapLabel = uilabel(app.M0R2Tab);
            app.M0MapLabel.Text = 'M0 map';
            app.R2MapLabel = uilabel(app.M0R2Tab);
            app.R2MapLabel.Text = 'R^2 map';
            app.ImageSelectorPanel2 = uipanel(app.M0R2Tab);
            app.ImageSelectorPanel2.Title = 'Image selection';
            app.ADCMapSlicesSlider2 = uislider(app.ImageSelectorPanel2);
            app.ADCMapSlicesSlider2.Limits = [1 200];
            app.ADCMapSlicesSlider2.MajorTicks = [];
            app.ADCMapSlicesSlider2.ValueChangedFcn = createCallbackFcn(app, @ADCMapSlicesSlider2ValueChanged, true);
            app.ADCMapSlicesSlider2.MinorTicks = [];
            app.ADCMapSlicesSlider2.Tooltip = {'Slice number slider'};
            app.ADCMapSlicesSlider2.Value = 1;
            app.SliceEditField_2Label_2 = uilabel(app.ImageSelectorPanel2);
            app.SliceEditField_2Label_2.Text = 'Slice';
            app.ADCMapSlicesEditField2 = uieditfield(app.ImageSelectorPanel2, 'numeric');
            app.ADCMapSlicesEditField2.Limits = [1 Inf];
            app.ADCMapSlicesEditField2.ValueDisplayFormat = '%.0f';
            app.ADCMapSlicesEditField2.ValueChangedFcn = createCallbackFcn(app, @ADCMapSlicesEditField2ValueChanged, true);
            app.ADCMapSlicesEditField2.Tooltip = {'Slice number'};
            app.ADCMapSlicesEditField2.Value = 1;
            app.ColorMapButtonGroup2 = uibuttongroup(app.M0R2Tab);
            app.ColorMapButtonGroup2.SelectionChangedFcn = createCallbackFcn(app, @ColorMapButtonGroup2SelectionChanged, true);
            app.ColorMapButtonGroup2.Title = 'Color map';
            app.grayButton2 = uiradiobutton(app.ColorMapButtonGroup2);
            app.grayButton2.Text = 'gray';
            app.grayButton2.Value = true;
            app.autumnButton2 = uiradiobutton(app.ColorMapButtonGroup2);
            app.autumnButton2.Text = 'autumn';
            app.winterButton2 = uiradiobutton(app.ColorMapButtonGroup2);
            app.winterButton2.Text = 'winter';
            app.parulaButton2 = uiradiobutton(app.ColorMapButtonGroup2);
            app.parulaButton2.Text = 'parula';
            app.hotButton2 = uiradiobutton(app.ColorMapButtonGroup2);
            app.hotButton2.Text = 'hot';
            app.ColorMapButtonGroup3 = uibuttongroup(app.M0R2Tab);
            app.ColorMapButtonGroup3.SelectionChangedFcn = createCallbackFcn(app, @ColorMapButtonGroup3SelectionChanged, true);
            app.ColorMapButtonGroup3.Title = 'Color map';
            app.grayButton3 = uiradiobutton(app.ColorMapButtonGroup3);
            app.grayButton3.Text = 'gray';
            app.autumnButton3 = uiradiobutton(app.ColorMapButtonGroup3);
            app.autumnButton3.Text = 'autumn';
            app.autumnButton3.Value = true;
            app.winterButton3 = uiradiobutton(app.ColorMapButtonGroup3);
            app.winterButton3.Text = 'winter';
            app.parulaButton3 = uiradiobutton(app.ColorMapButtonGroup3);
            app.parulaButton3.Text = 'parula';
            app.hotButton3 = uiradiobutton(app.ColorMapButtonGroup3);
            app.hotButton3.Text = 'hot';
            app.M0Image = uiimage(app.M0R2Tab);
            app.M0Image.ImageClickedFcn = createCallbackFcn(app, @M0ImageClicked, true);
            app.R2Image = uiimage(app.M0R2Tab);
            app.ROIbutton2 = uibutton(app.M0R2Tab, 'push');
            app.ROIbutton2.ButtonPushedFcn = createCallbackFcn(app, @ROIbuttonButtonPushed, true);
            app.ROIbutton2.Tooltip = {'Cross-hair / ROI drawing'};
            app.ROIbutton2.Text = 'ROI';
            app.EraseButton2 = uibutton(app.M0R2Tab, 'push');
            app.EraseButton2.ButtonPushedFcn = createCallbackFcn(app, @EraseButtonPushed, true);
            app.EraseButton2.Visible = 'off';
            app.EraseButton2.Tooltip = {'Erase ROI'};
            app.EraseButton2.Text = 'Erase';
            app.brushEditField_2Label = uilabel(app.M0R2Tab);
            app.brushEditField_2Label.Visible = 'off';
            app.brushEditField_2Label.Text = 'brush';
            app.BrushEditField2 = uieditfield(app.M0R2Tab, 'numeric');
            app.BrushEditField2.Limits = [1 5];
            app.BrushEditField2.ValueDisplayFormat = '%.0f';
            app.BrushEditField2.ValueChangedFcn = createCallbackFcn(app, @BrushEditField2ValueChanged, true);
            app.BrushEditField2.Visible = 'off';
            app.BrushEditField2.Tooltip = {'ROI drawing brush size (pixels)'};
            app.BrushEditField2.Value = 2;
            app.TabGroup2 = uitabgroup(app.ADCmapUIFigure);
            app.RawimagesTab = uitab(app.TabGroup2);
            app.RawimagesTab.Title = 'Raw images';
            app.RawDataFig = uiaxes(app.RawimagesTab);
            app.RawDataFig.XTick = [];
            app.RawDataFig.YTick = [];
            app.ImageLabel = uilabel(app.RawimagesTab);
            app.ImageLabel.Text = 'Image';
            app.ImageselectionPanel = uipanel(app.RawimagesTab);
            app.ImageselectionPanel.Title = 'Image selection';
            app.SlicesSlider = uislider(app.ImageselectionPanel);
            app.SlicesSlider.Limits = [1 200];
            app.SlicesSlider.MajorTicks = [];
            app.SlicesSlider.ValueChangedFcn = createCallbackFcn(app, @SlicesSliderValueChanged, true);
            app.SlicesSlider.MinorTicks = [];
            app.SlicesSlider.Tooltip = {'Slice number slider'};
            app.SlicesSlider.Value = 1;
            app.ImageSlider = uislider(app.ImageselectionPanel);
            app.ImageSlider.Limits = [1 200];
            app.ImageSlider.MajorTicks = [];
            app.ImageSlider.ValueChangedFcn = createCallbackFcn(app, @ImageSliderValueChanged, true);
            app.ImageSlider.MinorTicks = [];
            app.ImageSlider.Tooltip = {'Image number slider'};
            app.ImageSlider.Value = 1;
            app.SliceEditFieldLabel = uilabel(app.ImageselectionPanel);
            app.SliceEditFieldLabel.Text = 'Slice';
            app.SlicesEditField = uieditfield(app.ImageselectionPanel, 'numeric');
            app.SlicesEditField.Limits = [1 Inf];
            app.SlicesEditField.ValueDisplayFormat = '%.0f';
            app.SlicesEditField.ValueChangedFcn = createCallbackFcn(app, @SlicesEditFieldValueChanged, true);
            app.SlicesEditField.Tooltip = {'Slice number'};
            app.SlicesEditField.Value = 1;
            app.ImageLabel_2 = uilabel(app.ImageselectionPanel);
            app.ImageLabel_2.Text = 'Image';
            app.ImageEditField = uieditfield(app.ImageselectionPanel, 'numeric');
            app.ImageEditField.Limits = [1 Inf];
            app.ImageEditField.ValueDisplayFormat = '%.0f';
            app.ImageEditField.ValueChangedFcn = createCallbackFcn(app, @SlicesEditFieldValueChanged, true);
            app.ImageEditField.Tooltip = {'Image number'};
            app.ImageEditField.Value = 1;
            app.MasksettingsPanel = uipanel(app.RawimagesTab);
            app.MasksettingsPanel.Title = 'Mask settings';
            app.ThresholdSlider = uislider(app.MasksettingsPanel);
            app.ThresholdSlider.Limits = [1 16384];
            app.ThresholdSlider.MajorTicks = [];
            app.ThresholdSlider.ValueChangedFcn = createCallbackFcn(app, @ThresholdSliderValueChanged, true);
            app.ThresholdSlider.MinorTicks = [];
            app.ThresholdSlider.Tooltip = {'Mask threshold slider'};
            app.ThresholdSlider.Value = 1;
            app.ErodeButton = uibutton(app.MasksettingsPanel, 'push');
            app.ErodeButton.ButtonPushedFcn = createCallbackFcn(app, @ErodeButtonPushed, true);
            app.ErodeButton.Tooltip = {'Mask erosion'};
            app.ErodeButton.Text = 'Erode';
            app.DilateButton = uibutton(app.MasksettingsPanel, 'push');
            app.DilateButton.ButtonPushedFcn = createCallbackFcn(app, @DilateButtonPushed, true);
            app.DilateButton.Tooltip = {'Mask dilation'};
            app.DilateButton.Text = 'Dilate';
            app.CloseButton = uibutton(app.MasksettingsPanel, 'push');
            app.CloseButton.ButtonPushedFcn = createCallbackFcn(app, @CloseButtonPushed, true);
            app.CloseButton.Tooltip = {'Mask closing'};
            app.CloseButton.Text = 'Close';
            app.OpenButton = uibutton(app.MasksettingsPanel, 'push');
            app.OpenButton.ButtonPushedFcn = createCallbackFcn(app, @OpenButtonPushed, true);
            app.OpenButton.Tooltip = {'Mask opening'};
            app.OpenButton.Text = 'Open';
            app.ThresholdEditFieldLabel = uilabel(app.MasksettingsPanel);
            app.ThresholdEditFieldLabel.Text = 'Threshold';
            app.ThresholdEditField = uieditfield(app.MasksettingsPanel, 'numeric');
            app.ThresholdEditField.Limits = [0 Inf];
            app.ThresholdEditField.ValueDisplayFormat = '%.0f';
            app.ThresholdEditField.ValueChangedFcn = createCallbackFcn(app, @ThresholdEditFieldValueChanged, true);
            app.ThresholdEditField.Tooltip = {'Mask threshold'};
            app.ImagewindowlevelPanel = uipanel(app.RawimagesTab);
            app.ImagewindowlevelPanel.Title = 'Image window/level';
            app.WindowSlider = uislider(app.ImagewindowlevelPanel);
            app.WindowSlider.Limits = [0 32768];
            app.WindowSlider.MajorTicks = [];
            app.WindowSlider.ValueChangedFcn = createCallbackFcn(app, @WindowSliderValueChanged, true);
            app.WindowSlider.MinorTicks = [];
            app.WindowSlider.Tooltip = {'Window'};
            app.WindowSlider.Value = 16384;
            app.WEditFieldLabel = uilabel(app.ImagewindowlevelPanel);
            app.WEditFieldLabel.Text = 'W';
            app.WindowEditField = uieditfield(app.ImagewindowlevelPanel, 'numeric');
            app.WindowEditField.Limits = [0 Inf];
            app.WindowEditField.ValueDisplayFormat = '%.0f';
            app.WindowEditField.ValueChangedFcn = createCallbackFcn(app, @WindowEditFieldValueChanged, true);
            app.WindowEditField.Tooltip = {'Window'};
            app.LevelSlider = uislider(app.ImagewindowlevelPanel);
            app.LevelSlider.Limits = [0 32768];
            app.LevelSlider.MajorTicks = [];
            app.LevelSlider.ValueChangedFcn = createCallbackFcn(app, @LevelSliderValueChanged, true);
            app.LevelSlider.MinorTicks = [];
            app.LevelSlider.Tooltip = {'Level'};
            app.LevelSlider.Value = 16384;
            app.AutoLWButton = uibutton(app.ImagewindowlevelPanel, 'state');
            app.AutoLWButton.ValueChangedFcn = createCallbackFcn(app, @AutoLWButtonValueChanged, true);
            app.AutoLWButton.Tooltip = {'Automatic window and level setting'};
            app.AutoLWButton.Text = 'Auto';
            app.AutoLWButton.Value = true;
            app.LEditFieldLabel = uilabel(app.ImagewindowlevelPanel);
            app.LEditFieldLabel.Text = 'L';
            app.LevelEditField = uieditfield(app.ImagewindowlevelPanel, 'numeric');
            app.LevelEditField.Limits = [0 Inf];
            app.LevelEditField.ValueDisplayFormat = '%.0f';
            app.LevelEditField.ValueChangedFcn = createCallbackFcn(app, @LevelEditFieldValueChanged, true);
            app.LevelEditField.Tooltip = {'Level'};
            app.RawImage = uiimage(app.RawimagesTab);
            app.BvalueEditFieldLabel = uilabel(app.RawimagesTab);
            app.BvalueEditFieldLabel.Text = 'B-value';
            app.BvalueViewField = uieditfield(app.RawimagesTab, 'text');
            app.BvalueViewField.Tooltip = {'Diffusion weighted b-value'};
            app.GradEditFieldLabel = uilabel(app.RawimagesTab);
            app.GradEditFieldLabel.Text = 'Grad';
            app.GradViewField = uieditfield(app.RawimagesTab, 'text');
            app.GradViewField.Tooltip = {'Gradient directions'};
            app.FitparametersTab = uitab(app.TabGroup2);
            app.FitparametersTab.Title = 'Fit parameters';
            app.SliceselectionPanel = uipanel(app.FitparametersTab);
            app.SliceselectionPanel.Title = 'Slice selection';
            app.FirstsliceLabel = uilabel(app.SliceselectionPanel);
            app.FirstsliceLabel.Text = 'First slice';
            app.FirstSliceEditField = uieditfield(app.SliceselectionPanel, 'numeric');
            app.FirstSliceEditField.Limits = [1 200];
            app.FirstSliceEditField.ValueChangedFcn = createCallbackFcn(app, @FirstSliceEditFieldValueChanged, true);
            app.FirstSliceEditField.Enable = 'off';
            app.FirstSliceEditField.Tooltip = {'First fit slice'};
            app.FirstSliceEditField.Value = 1;
            app.LastsliceLabel = uilabel(app.SliceselectionPanel);
            app.LastsliceLabel.Text = 'Last slice';
            app.LastSliceEditField = uieditfield(app.SliceselectionPanel, 'numeric');
            app.LastSliceEditField.Limits = [1 200];
            app.LastSliceEditField.ValueChangedFcn = createCallbackFcn(app, @FirstSliceEditFieldValueChanged, true);
            app.LastSliceEditField.Enable = 'off';
            app.LastSliceEditField.Tooltip = {'Last fit slice'};
            app.LastSliceEditField.Value = 1;
            app.GoodnessoffitPanel = uipanel(app.FitparametersTab);
            app.GoodnessoffitPanel.Title = 'Goodness of fit';
            app.RsquareLabel = uilabel(app.GoodnessoffitPanel);
            app.RsquareLabel.Text = 'R-square';
            app.Rsquare = uieditfield(app.GoodnessoffitPanel, 'numeric');
            app.Rsquare.Limits = [0 1];
            app.Rsquare.ValueDisplayFormat = '%.2f';
            app.Rsquare.Enable = 'off';
            app.Rsquare.Tooltip = {'R-square fit threshold'};
            app.Rsquare.Value = 0.8;
            app.ThresholdR = uicheckbox(app.GoodnessoffitPanel);
            app.ThresholdR.Enable = 'off';
            app.ThresholdR.Tooltip = {'Check to use R-square criterium during fitting'};
            app.ThresholdR.Text = '';
            app.ThresholdLabel = uilabel(app.GoodnessoffitPanel);
            app.ThresholdLabel.Tooltip = {'Check to use R-square criterium during fitting'};
            app.ThresholdLabel.Text = 'Threshold';
            app.BvaluegradientdirectionselectionPanel = uipanel(app.FitparametersTab);
            app.BvaluegradientdirectionselectionPanel.Title = 'B-value / gradient direction selection';
            app.CheckBox_1 = uicheckbox(app.BvaluegradientdirectionselectionPanel);
            app.CheckBox_1.Text = '1';
            app.CheckBox_1.Value = true;
            app.CheckBox_2 = uicheckbox(app.BvaluegradientdirectionselectionPanel);
            app.CheckBox_2.Text = '2';
            app.CheckBox_2.Value = true;
            app.CheckBox_3 = uicheckbox(app.BvaluegradientdirectionselectionPanel);
            app.CheckBox_3.Text = '3';
            app.CheckBox_3.Value = true;
            app.CheckBox_4 = uicheckbox(app.BvaluegradientdirectionselectionPanel);
            app.CheckBox_4.Text = '4';
            app.CheckBox_4.Value = true;
            app.CheckBox_5 = uicheckbox(app.BvaluegradientdirectionselectionPanel);
            app.CheckBox_5.Text = '5';
            app.CheckBox_5.Value = true;
            app.CheckBox_6 = uicheckbox(app.BvaluegradientdirectionselectionPanel);
            app.CheckBox_6.Text = '6';
            app.CheckBox_6.Value = true;
            app.CheckBox_7 = uicheckbox(app.BvaluegradientdirectionselectionPanel);
            app.CheckBox_7.Text = '7';
            app.CheckBox_7.Value = true;
            app.CheckBox_8 = uicheckbox(app.BvaluegradientdirectionselectionPanel);
            app.CheckBox_8.Text = '8';
            app.CheckBox_8.Value = true;
            app.CheckBox_9 = uicheckbox(app.BvaluegradientdirectionselectionPanel);
            app.CheckBox_9.Text = '9';
            app.CheckBox_9.Value = true;
            app.CheckBox_10 = uicheckbox(app.BvaluegradientdirectionselectionPanel);
            app.CheckBox_10.Text = '10';
            app.CheckBox_10.Value = true;
            app.CheckBox_11 = uicheckbox(app.BvaluegradientdirectionselectionPanel);
            app.CheckBox_11.Text = '11';
            app.CheckBox_11.Value = true;
            app.CheckBox_12 = uicheckbox(app.BvaluegradientdirectionselectionPanel);
            app.CheckBox_12.Text = '12';
            app.CheckBox_12.Value = true;
            app.CheckBox_13 = uicheckbox(app.BvaluegradientdirectionselectionPanel);
            app.CheckBox_13.Text = '13';
            app.CheckBox_13.Value = true;
            app.CheckBox_14 = uicheckbox(app.BvaluegradientdirectionselectionPanel);
            app.CheckBox_14.Text = '14';
            app.CheckBox_14.Value = true;
            app.CheckBox_15 = uicheckbox(app.BvaluegradientdirectionselectionPanel);
            app.CheckBox_15.Text = '15';
            app.CheckBox_15.Value = true;
            app.CheckBox_16 = uicheckbox(app.BvaluegradientdirectionselectionPanel);
            app.CheckBox_16.Text = '16';
            app.CheckBox_16.Value = true;
            app.CheckBox_17 = uicheckbox(app.BvaluegradientdirectionselectionPanel);
            app.CheckBox_17.Text = '17';
            app.CheckBox_17.Value = true;
            app.CheckBox_18 = uicheckbox(app.BvaluegradientdirectionselectionPanel);
            app.CheckBox_18.Text = '18';
            app.CheckBox_18.Value = true;
            app.CheckBox_19 = uicheckbox(app.BvaluegradientdirectionselectionPanel);
            app.CheckBox_19.Text = '19';
            app.CheckBox_19.Value = true;
            app.CheckBox_20 = uicheckbox(app.BvaluegradientdirectionselectionPanel);
            app.CheckBox_20.Text = '20';
            app.CheckBox_20.Value = true;
            app.CheckBox_21 = uicheckbox(app.BvaluegradientdirectionselectionPanel);
            app.CheckBox_21.Text = '21';
            app.CheckBox_21.Value = true;
            app.CheckBox_22 = uicheckbox(app.BvaluegradientdirectionselectionPanel);
            app.CheckBox_22.Text = '22';
            app.CheckBox_22.Value = true;
            app.CheckBox_23 = uicheckbox(app.BvaluegradientdirectionselectionPanel);
            app.CheckBox_23.Text = '23';
            app.CheckBox_23.Value = true;
            app.CheckBox_24 = uicheckbox(app.BvaluegradientdirectionselectionPanel);
            app.CheckBox_24.Text = '24';
            app.CheckBox_24.Value = true;
            app.CheckBox_25 = uicheckbox(app.BvaluegradientdirectionselectionPanel);
            app.CheckBox_25.Text = '25';
            app.CheckBox_25.Value = true;
            app.CheckBox_26 = uicheckbox(app.BvaluegradientdirectionselectionPanel);
            app.CheckBox_26.Text = '26';
            app.CheckBox_26.Value = true;
            app.CheckBox_27 = uicheckbox(app.BvaluegradientdirectionselectionPanel);
            app.CheckBox_27.Text = '27';
            app.CheckBox_27.Value = true;
            app.CheckBox_28 = uicheckbox(app.BvaluegradientdirectionselectionPanel);
            app.CheckBox_28.Text = '28';
            app.CheckBox_28.Value = true;
            app.CheckBox_29 = uicheckbox(app.BvaluegradientdirectionselectionPanel);
            app.CheckBox_29.Text = '29';
            app.CheckBox_29.Value = true;
            app.CheckBox_30 = uicheckbox(app.BvaluegradientdirectionselectionPanel);
            app.CheckBox_30.Text = '30';
            app.CheckBox_30.Value = true;
            app.CheckBox_31 = uicheckbox(app.BvaluegradientdirectionselectionPanel);
            app.CheckBox_31.Text = '31';
            app.CheckBox_31.Value = true;
            app.CheckBox_32 = uicheckbox(app.BvaluegradientdirectionselectionPanel);
            app.CheckBox_32.Text = '32';
            app.CheckBox_32.Value = true;
            app.CheckBox_33 = uicheckbox(app.BvaluegradientdirectionselectionPanel);
            app.CheckBox_33.Text = '33';
            app.CheckBox_33.Value = true;
            app.CheckBox_34 = uicheckbox(app.BvaluegradientdirectionselectionPanel);
            app.CheckBox_34.Text = '34';
            app.CheckBox_34.Value = true;
            app.CheckBox_35 = uicheckbox(app.BvaluegradientdirectionselectionPanel);
            app.CheckBox_35.Text = '35';
            app.CheckBox_35.Value = true;
            app.CheckBox_36 = uicheckbox(app.BvaluegradientdirectionselectionPanel);
            app.CheckBox_36.Text = '36';
            app.CheckBox_36.Value = true;
            app.CheckBox_37 = uicheckbox(app.BvaluegradientdirectionselectionPanel);
            app.CheckBox_37.Text = '37';
            app.CheckBox_37.Value = true;
            app.CheckBox_38 = uicheckbox(app.BvaluegradientdirectionselectionPanel);
            app.CheckBox_38.Text = '38';
            app.CheckBox_38.Value = true;
            app.CheckBox_39 = uicheckbox(app.BvaluegradientdirectionselectionPanel);
            app.CheckBox_39.Text = '39';
            app.CheckBox_39.Value = true;
            app.CheckBox_40 = uicheckbox(app.BvaluegradientdirectionselectionPanel);
            app.CheckBox_40.Text = '40';
            app.CheckBox_40.Value = true;
            app.CheckBox_41 = uicheckbox(app.BvaluegradientdirectionselectionPanel);
            app.CheckBox_41.Text = '41';
            app.CheckBox_41.Value = true;
            app.CheckBox_42 = uicheckbox(app.BvaluegradientdirectionselectionPanel);
            app.CheckBox_42.Text = '42';
            app.CheckBox_42.Value = true;
            app.CheckBox_43 = uicheckbox(app.BvaluegradientdirectionselectionPanel);
            app.CheckBox_43.Text = '43';
            app.CheckBox_43.Value = true;
            app.CheckBox_44 = uicheckbox(app.BvaluegradientdirectionselectionPanel);
            app.CheckBox_44.Text = '44';
            app.CheckBox_44.Value = true;
            app.CheckBox_45 = uicheckbox(app.BvaluegradientdirectionselectionPanel);
            app.CheckBox_45.Text = '45';
            app.CheckBox_45.Value = true;
            app.CheckBox_46 = uicheckbox(app.BvaluegradientdirectionselectionPanel);
            app.CheckBox_46.Text = '46';
            app.CheckBox_46.Value = true;
            app.CheckBox_47 = uicheckbox(app.BvaluegradientdirectionselectionPanel);
            app.CheckBox_47.Text = '47';
            app.CheckBox_47.Value = true;
            app.CheckBox_48 = uicheckbox(app.BvaluegradientdirectionselectionPanel);
            app.CheckBox_48.Text = '48';
            app.CheckBox_48.Value = true;
            app.CheckBox_49 = uicheckbox(app.BvaluegradientdirectionselectionPanel);
            app.CheckBox_49.Text = '49';
            app.CheckBox_49.Value = true;
            app.CheckBox_50 = uicheckbox(app.BvaluegradientdirectionselectionPanel);
            app.CheckBox_50.Text = '50';
            app.CheckBox_50.Value = true;
            app.CheckBox_51 = uicheckbox(app.BvaluegradientdirectionselectionPanel);
            app.CheckBox_51.Text = '51';
            app.CheckBox_51.Value = true;
            app.CheckBox_52 = uicheckbox(app.BvaluegradientdirectionselectionPanel);
            app.CheckBox_52.Text = '52';
            app.CheckBox_52.Value = true;
            app.CheckBox_53 = uicheckbox(app.BvaluegradientdirectionselectionPanel);
            app.CheckBox_53.Text = '53';
            app.CheckBox_53.Value = true;
            app.CheckBox_54 = uicheckbox(app.BvaluegradientdirectionselectionPanel);
            app.CheckBox_54.Text = '54';
            app.CheckBox_54.Value = true;
            app.CheckBox_55 = uicheckbox(app.BvaluegradientdirectionselectionPanel);
            app.CheckBox_55.Text = '55';
            app.CheckBox_55.Value = true;
            app.CheckBox_56 = uicheckbox(app.BvaluegradientdirectionselectionPanel);
            app.CheckBox_56.Text = '56';
            app.CheckBox_56.Value = true;
            app.CheckBox_57 = uicheckbox(app.BvaluegradientdirectionselectionPanel);
            app.CheckBox_57.Text = '57';
            app.CheckBox_57.Value = true;
            app.CheckBox_58 = uicheckbox(app.BvaluegradientdirectionselectionPanel);
            app.CheckBox_58.Text = '58';
            app.CheckBox_58.Value = true;
            app.CheckBox_59 = uicheckbox(app.BvaluegradientdirectionselectionPanel);
            app.CheckBox_59.Text = '59';
            app.CheckBox_59.Value = true;
            app.CheckBox_60 = uicheckbox(app.BvaluegradientdirectionselectionPanel);
            app.CheckBox_60.Text = '60';
            app.CheckBox_60.Value = true;
            app.CheckBox_61 = uicheckbox(app.BvaluegradientdirectionselectionPanel);
            app.CheckBox_61.Text = '61';
            app.CheckBox_61.Value = true;
            app.CheckBox_62 = uicheckbox(app.BvaluegradientdirectionselectionPanel);
            app.CheckBox_62.Text = '62';
            app.CheckBox_62.Value = true;
            app.CheckBox_63 = uicheckbox(app.BvaluegradientdirectionselectionPanel);
            app.CheckBox_63.Text = '63';
            app.CheckBox_63.Value = true;
            app.CheckBox_64 = uicheckbox(app.BvaluegradientdirectionselectionPanel);
            app.CheckBox_64.Text = '64';
            app.CheckBox_64.Value = true;
            app.UsergradienttablePanel = uipanel(app.FitparametersTab);
            app.UsergradienttablePanel.Title = 'User gradient table';
            app.ImportGradTableButton = uibutton(app.UsergradienttablePanel, 'push');
            app.ImportGradTableButton.ButtonPushedFcn = createCallbackFcn(app, @ImportGradTableButtonPushed, true);
            app.ImportGradTableButton.Tooltip = {'Import gradient table'};
            app.ImportGradTableButton.Text = 'Import';
            app.TabGroup4 = uitabgroup(app.ADCmapUIFigure);
            app.SequenceparametersTab = uitab(app.TabGroup4);
            app.SequenceparametersTab.Title = 'Sequence parameters';
            app.TRmsEditFieldLabel = uilabel(app.SequenceparametersTab);
            app.TRmsEditFieldLabel.Text = 'TR (ms)';
            app.TRViewField = uieditfield(app.SequenceparametersTab, 'numeric');
            app.TRViewField.Limits = [0 Inf];
            app.TRViewField.Editable = 'off';
            app.TRViewField.Tooltip = {'Repetition time'};
            app.TEmsLabel = uilabel(app.SequenceparametersTab);
            app.TEmsLabel.Text = 'TE (ms)';
            app.TEViewField = uieditfield(app.SequenceparametersTab, 'numeric');
            app.TEViewField.Limits = [0 Inf];
            app.TEViewField.ValueChangedFcn = createCallbackFcn(app, @TEViewFieldValueChanged, true);
            app.TEViewField.Editable = 'off';
            app.TEViewField.Tooltip = {'Echo time'};
            app.dimxEditFieldLabel = uilabel(app.SequenceparametersTab);
            app.dimxEditFieldLabel.Text = 'dimx';
            app.dimxViewField = uieditfield(app.SequenceparametersTab, 'numeric');
            app.dimxViewField.Limits = [0 Inf];
            app.dimxViewField.ValueDisplayFormat = '%.0f';
            app.dimxViewField.Editable = 'off';
            app.dimxViewField.Tooltip = {'Readout matrix size'};
            app.dimyEditFieldLabel = uilabel(app.SequenceparametersTab);
            app.dimyEditFieldLabel.Text = 'dimy';
            app.dimyViewField = uieditfield(app.SequenceparametersTab, 'numeric');
            app.dimyViewField.Limits = [0 Inf];
            app.dimyViewField.ValueDisplayFormat = '%.0f';
            app.dimyViewField.Editable = 'off';
            app.dimyViewField.Tooltip = {'Phase-encoding matrix size'};
            app.SlicesEditFieldLabel = uilabel(app.SequenceparametersTab);
            app.SlicesEditFieldLabel.Text = 'Slices';
            app.SlicesViewField = uieditfield(app.SequenceparametersTab, 'numeric');
            app.SlicesViewField.Limits = [0 Inf];
            app.SlicesViewField.ValueDisplayFormat = '%.0f';
            app.SlicesViewField.Editable = 'off';
            app.SlicesViewField.Tooltip = {'Number of slices'};
            app.ImagesLabel = uilabel(app.SequenceparametersTab);
            app.ImagesLabel.Text = 'Images';
            app.NRImagesViewField = uieditfield(app.SequenceparametersTab, 'numeric');
            app.NRImagesViewField.Limits = [0 Inf];
            app.NRImagesViewField.Editable = 'off';
            app.NRImagesViewField.Tooltip = {'Number of images'};
            app.FOVmmEditFieldLabel = uilabel(app.SequenceparametersTab);
            app.FOVmmEditFieldLabel.Text = 'FOV (mm)';
            app.FOVViewField = uieditfield(app.SequenceparametersTab, 'numeric');
            app.FOVViewField.Limits = [0 Inf];
            app.FOVViewField.Editable = 'off';
            app.FOVViewField.Tooltip = {'Field-of-view'};
            app.SlicethicknessmmLabel = uilabel(app.SequenceparametersTab);
            app.SlicethicknessmmLabel.Text = 'Slice thickness (mm)';
            app.SLTViewField = uieditfield(app.SequenceparametersTab, 'numeric');
            app.SLTViewField.Limits = [0 Inf];
            app.SLTViewField.Editable = 'off';
            app.SLTViewField.Tooltip = {'Slice thickness'};
            app.AspectratioLabel = uilabel(app.SequenceparametersTab);
            app.AspectratioLabel.Text = 'Aspect ratio';
            app.AspectRatioViewField = uieditfield(app.SequenceparametersTab, 'numeric');
            app.AspectRatioViewField.Limits = [0.25 4];
            app.AspectRatioViewField.ValueDisplayFormat = '%.1f';
            app.AspectRatioViewField.Editable = 'off';
            app.AspectRatioViewField.Tooltip = {'Image aspect ratio'};
            app.AspectRatioViewField.Value = 1;
            app.DicomfolderLabel = uilabel(app.SequenceparametersTab);
            app.DicomfolderLabel.Text = 'Dicom folder';
            app.DicomDirViewField = uieditfield(app.SequenceparametersTab, 'text');
            app.DicomDirViewField.Editable = 'off';
            app.DicomDirViewField.Tooltip = {'DICOM data folder'};
            % Show the figure after all components are created
            app.ADCmapUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = ADCmapp

            runningApp = getRunningApp(app);

            % Check for running singleton app
            if isempty(runningApp)

                % Create UIFigure and components
                createComponents(app)

                % Register the app with App Designer
                registerApp(app, app.ADCmapUIFigure)

                % Execute the startup function
                runStartupFcn(app, @StartupFcn)
            else

                % Focus the running singleton app
                figure(runningApp.ADCmapUIFigure)

                app = runningApp;
            end

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.ADCmapUIFigure)
        end
    end
end